classdef ground_station_flight_software_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.EditField
        UDPConnectionSettingsPanel      matlab.ui.container.Panel
        ArduinoIPAddressEditFieldLabel  matlab.ui.control.Label
        ArduinoIPAddressEditField       matlab.ui.control.EditField
        ViconIPAddressEditField_2Label  matlab.ui.control.Label
        ViconIPAddressEditField         matlab.ui.control.EditField
        StationIPAddressEditFieldLabel  matlab.ui.control.Label
        StationIPAddressEditField       matlab.ui.control.EditField
        ArduinoPortEditFieldLabel       matlab.ui.control.Label
        ArduinoPortEditField            matlab.ui.control.NumericEditField
        ViconPortLabel                  matlab.ui.control.Label
        ViconPortEditField              matlab.ui.control.NumericEditField
        StationPortEditFieldLabel       matlab.ui.control.Label
        StationPortEditField            matlab.ui.control.NumericEditField
        ConnectButton                   matlab.ui.control.Button
        ViconObjectEditFieldLabel       matlab.ui.control.Label
        ViconObjectEditField            matlab.ui.control.EditField
        ArduinoCheckBox                 matlab.ui.control.CheckBox
        ViconCheckBox                   matlab.ui.control.CheckBox
        DisconnectButton                matlab.ui.control.StateButton
        TelemetryPanel                  matlab.ui.container.Panel
        ArtificialHorizondegLabel       matlab.ui.control.Label
        ArtificialHorizondeg            Aero.ui.control.ArtificialHorizon
        AltimetercmLabel                matlab.ui.control.Label
        Altimetercm                     Aero.ui.control.Altimeter
        HeadingdegHeadingIndicatorLabel  matlab.ui.control.Label
        HeadingdegHeadingIndicator      Aero.ui.control.HeadingIndicator
        ThrustInputpwmRPMIndicatorLabel  matlab.ui.control.Label
        ThrustInputpwmRPMIndicator      Aero.ui.control.RPMIndicator
        DataStreamTextAreaLabel         matlab.ui.control.Label
        DataStreamTextArea              matlab.ui.control.TextArea
        ControlPanel                    matlab.ui.container.Panel
        RunButton                       matlab.ui.control.StateButton
        SetAltutudemEditFieldLabel      matlab.ui.control.Label
        SetAltutudemEditField           matlab.ui.control.NumericEditField
        PWSliderLabel                   matlab.ui.control.Label
        PWSlider                        matlab.ui.control.Slider
        ControlTypeButtonGroup          matlab.ui.container.ButtonGroup
        TelemetryButton                 matlab.ui.control.RadioButton
        AttAltManualPWMButton           matlab.ui.control.RadioButton
        AttAltEDFOFFButton              matlab.ui.control.RadioButton
        AttAltEDFONButton               matlab.ui.control.RadioButton
        ManualEDFButton                 matlab.ui.control.RadioButton
        CalculatedInputsPanel           matlab.ui.container.Panel
        DeflectorAnglesPanel            matlab.ui.container.Panel
        Deflector1TextAreaLabel         matlab.ui.control.Label
        Deflector1TextArea              matlab.ui.control.TextArea
        Deflector3TextAreaLabel         matlab.ui.control.Label
        Deflector3TextArea              matlab.ui.control.TextArea
        Deflector4TextAreaLabel         matlab.ui.control.Label
        Deflector4TextArea              matlab.ui.control.TextArea
        Deflector2TextAreaLabel         matlab.ui.control.Label
        Deflector2TextArea              matlab.ui.control.TextArea
        ThrustTextArea_2Label           matlab.ui.control.Label
        ThrustTextArea                  matlab.ui.control.TextArea
        SimulatedInputsPanel            matlab.ui.container.Panel
        DeflectorAnglesPanel_2          matlab.ui.container.Panel
        Deflector1calcTextAreaLabel     matlab.ui.control.Label
        Deflector1calcTextArea          matlab.ui.control.TextArea
        Deflector2calcTextAreaLabel     matlab.ui.control.Label
        Deflector2calcTextArea          matlab.ui.control.TextArea
        Deflector3calcTextAreaLabel     matlab.ui.control.Label
        Deflector3calcTextArea          matlab.ui.control.TextArea
        Deflector4calcTextAreaLabel     matlab.ui.control.Label
        Deflector4calcTextArea          matlab.ui.control.TextArea
        PsiDataPanel                    matlab.ui.container.Panel
        PsiErrorTextAreaLabel           matlab.ui.control.Label
        PsiErrorTextArea                matlab.ui.control.TextArea
        PsiRateErrorTextAreaLabel       matlab.ui.control.Label
        PsiRateErrorTextArea            matlab.ui.control.TextArea
        SPsiTextAreaLabel               matlab.ui.control.Label
        SPsiTextArea                    matlab.ui.control.TextArea
        TorquePsiTextAreaLabel          matlab.ui.control.Label
        TorquePsiTextArea               matlab.ui.control.TextArea
        PsiTextAreaLabel                matlab.ui.control.Label
        PsiTextArea                     matlab.ui.control.TextArea
        LambdaPsiTextAreaLabel          matlab.ui.control.Label
        LambdaPsiTextArea               matlab.ui.control.TextArea
        APsiTextAreaLabel               matlab.ui.control.Label
        APsiTextArea                    matlab.ui.control.TextArea
        BPsiTextAreaLabel               matlab.ui.control.Label
        BPsiTextArea                    matlab.ui.control.TextArea
        EpsPsiTextAreaLabel             matlab.ui.control.Label
        EpsPsiTextArea                  matlab.ui.control.TextArea
        ThetaDataPanel                  matlab.ui.container.Panel
        ThetaErrorTextAreaLabel         matlab.ui.control.Label
        ThetaErrorTextArea              matlab.ui.control.TextArea
        ThetaRateErrorTextAreaLabel     matlab.ui.control.Label
        ThetaRateErrorTextArea          matlab.ui.control.TextArea
        SThetaTextAreaLabel             matlab.ui.control.Label
        SThetaTextArea                  matlab.ui.control.TextArea
        TorqueThetaTextAreaLabel        matlab.ui.control.Label
        TorqueThetaTextArea             matlab.ui.control.TextArea
        ThetaTextAreaLabel              matlab.ui.control.Label
        ThetaTextArea                   matlab.ui.control.TextArea
        LambdaThetaTextAreaLabel        matlab.ui.control.Label
        LambdaThetaTextArea             matlab.ui.control.TextArea
        AThetaTextAreaLabel             matlab.ui.control.Label
        AThetaTextArea                  matlab.ui.control.TextArea
        BThetaTextAreaLabel             matlab.ui.control.Label
        BThetaTextArea                  matlab.ui.control.TextArea
        EpsThetaTextAreaLabel           matlab.ui.control.Label
        EpsThetaTextArea                matlab.ui.control.TextArea
        PsiDataPanel_2                  matlab.ui.container.Panel
        PhiErrorTextAreaLabel           matlab.ui.control.Label
        PhiErrorTextArea                matlab.ui.control.TextArea
        PhiRateErrorTextAreaLabel       matlab.ui.control.Label
        PhiRateErrorTextArea            matlab.ui.control.TextArea
        SPhiTextAreaLabel               matlab.ui.control.Label
        SPhiTextArea                    matlab.ui.control.TextArea
        TorquePhiTextAreaLabel          matlab.ui.control.Label
        TorquePhiTextArea               matlab.ui.control.TextArea
        PhiTextArea_2Label              matlab.ui.control.Label
        PhiTextArea                     matlab.ui.control.TextArea
        LambdaPhiTextAreaLabel          matlab.ui.control.Label
        LambdaPhiTextArea               matlab.ui.control.TextArea
        APhiTextAreaLabel               matlab.ui.control.Label
        APhiTextArea                    matlab.ui.control.TextArea
        BPhiTextAreaLabel               matlab.ui.control.Label
        BPhiTextArea                    matlab.ui.control.TextArea
        EpsPhiTextAreaLabel             matlab.ui.control.Label
        EpsPhiTextArea                  matlab.ui.control.TextArea
        ControllerVariablesPanel        matlab.ui.container.Panel
        HorizontalPositionPanel         matlab.ui.container.Panel
        LambdaXEditFieldLabel           matlab.ui.control.Label
        LambdaXEditField                matlab.ui.control.NumericEditField
        AXEditFieldLabel                matlab.ui.control.Label
        AXEditField                     matlab.ui.control.NumericEditField
        BXEditFieldLabel                matlab.ui.control.Label
        BXEditField                     matlab.ui.control.NumericEditField
        epsXEditFieldLabel              matlab.ui.control.Label
        epsXEditField                   matlab.ui.control.NumericEditField
        LambdaYEditFieldLabel           matlab.ui.control.Label
        LambdaYEditField                matlab.ui.control.NumericEditField
        AYEditFieldLabel                matlab.ui.control.Label
        AYEditField                     matlab.ui.control.NumericEditField
        BYEditFieldLabel                matlab.ui.control.Label
        BYEditField                     matlab.ui.control.NumericEditField
        epsYEditFieldLabel              matlab.ui.control.Label
        epsYEditField                   matlab.ui.control.NumericEditField
        AltitudePanel                   matlab.ui.container.Panel
        LambdaZEditFieldLabel           matlab.ui.control.Label
        LambdaZEditField                matlab.ui.control.NumericEditField
        AZEditFieldLabel                matlab.ui.control.Label
        AZEditField                     matlab.ui.control.NumericEditField
        BZEditFieldLabel                matlab.ui.control.Label
        BZEditField                     matlab.ui.control.NumericEditField
        epsZEditFieldLabel              matlab.ui.control.Label
        epsZEditField                   matlab.ui.control.NumericEditField
        AttitudePanel                   matlab.ui.container.Panel
        LambdaPhiEditFieldLabel         matlab.ui.control.Label
        LambdaPhiEditField              matlab.ui.control.NumericEditField
        APhiEditFieldLabel              matlab.ui.control.Label
        APhiEditField                   matlab.ui.control.NumericEditField
        BPhiEditFieldLabel              matlab.ui.control.Label
        BPhiEditField                   matlab.ui.control.NumericEditField
        epsPhiEditFieldLabel            matlab.ui.control.Label
        epsPhiEditField                 matlab.ui.control.NumericEditField
        LambdaThetaEditFieldLabel       matlab.ui.control.Label
        LambdaThetaEditField            matlab.ui.control.NumericEditField
        AThetaEditFieldLabel            matlab.ui.control.Label
        AThetaEditField                 matlab.ui.control.NumericEditField
        BThetaEditFieldLabel            matlab.ui.control.Label
        BThetaEditField                 matlab.ui.control.NumericEditField
        epsThetaEditFieldLabel          matlab.ui.control.Label
        epsThetaEditField               matlab.ui.control.NumericEditField
        LambdaPsiEditFieldLabel         matlab.ui.control.Label
        LambdaPsiEditField              matlab.ui.control.NumericEditField
        APsiEditFieldLabel              matlab.ui.control.Label
        APsiEditField                   matlab.ui.control.NumericEditField
        BPsiEditFieldLabel              matlab.ui.control.Label
        BPsiEditField                   matlab.ui.control.NumericEditField
        epsPsiEditFieldLabel            matlab.ui.control.Label
        epsPsiEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        viconObject % Description
        vState = 0; % State of the vehicle
        arduinoIP;
        arduinoPort;
        viconIP;
        viconPort;
        stationIP;
        stationPort;
        udps;
        udpr;
        
        MyClient
        
        % Vicon Data
        GlobalTranslation;
        GlobalEulerXYZ;
        v_pos_x = 0;
        v_pos_y = 0;
        v_pos_z = 0;
        v_phi = 0;
        v_theta = 0;
        v_psi = 0;
        pwmInput = 0;
        m_phi = 0;
        m_theta = 0;
        m_psi = 0;
        
        
        setAlt; % Altitude ref
        x_des = 0; % X pos ref
        y_des = 0; % Y pos ref
        pw_des = 0; % pwm user input
        
        
        flagConnected = 0;
        controlType = 0;
        
        
        loopTime = 0.05;
        
        onTime = cputime;
        
        lambda_phi = 0; lambda_theta = 0; lambda_psi = 0;
        A_phi = 0; A_theta = 0; A_psi = 0;
        B_phi = 0; B_theta = 0; B_psi = 0;
        eps_phi = 0; eps_theta = 0; eps_psi = 0;
        
        lambda_x = 0; lambda_y = 0; lambda_z = 0;
        A_x = 0; A_y = 0; A_z = 0;
        B_x = 0; B_y = 0; B_z = 0;
        eps_x = 0; eps_y = 0; eps_z = 0;
        
        % vehicle data
        r_x = 0;
        r_y = 0;
        r_z = 0;
        
        r_des_x = 0;
        r_des_y = 0;
        r_des_z = 0;
        
        r_d_x = 0;
        r_d_y = 0;
        r_d_z = 0;
        
        r_des_d_x = 0;
        r_des_d_y = 0;
        r_des_d_z = 0;
        
        r_phi = 0;
        r_theta = 0;
        r_psi = 0;
        
        r_des_phi = 0;
        r_des_theta = 0;
        r_des_psi = 0;
        
        r_w1 = 0;
        r_w2 = 0;
        r_w3 = 0;
        
        r_des_w1 = 0;
        r_des_w2 = 0;
        r_des_w3 = 0;
        
        r_lambda_phi = 0;
        r_lambda_theta = 0;
        r_lambda_psi = 0;
        
        r_A_phi =0;
        r_A_theta =0;
        r_A_psi =0;
        
        r_B_phi =0;
        r_B_theta =0;
        r_B_psi =0;
        
        r_eps_phi =0;
        r_eps_theta =0;
        r_eps_psi =0;
        
        r_lambda_z =0;
        r_A_z = 0;
        r_B_z = 0;
        r_eps_z = 0;
        
        r_uz = 0;
        r_g1 = 0;
        r_g2 = 0;
        r_g3 = 0;
        r_g4 = 0;
        
        calc_uz = 0;
        
        calc_error_angles = 0;
        calc_d_error_angles = 0;
        calc_s_angles = 0;
        calc_torque_angles = 0;
        calc_force_angles = 0;
        calc_deflector_angles = 0;
        
        phi_buffer = zeros(1,50);
        
    end
    
    properties (Access = public)
        mData % Description
    end
    
    methods (Access = private)
        
        function viconReceiveData(app,viconObject)
            while   app.MyClient.GetFrame().Result ~= ViconDataStreamSDK.DotNET.Result.Success
                fprintf('.');
            end
            SubjectCount = app.MyClient.GetSubjectCount().SubjectCount;
            for SubjectIndex = 0 : typecast(SubjectCount,'int32') -1
                SubjectName = app.MyClient.GetSubjectName( typecast( SubjectIndex, 'uint32') ).SubjectName;
                %if strcmp(SubjectName,viconObject)
                if(SubjectName == app.viconObject)
                    SegmentCount = 1;
                    for SegmentIndex = 0:0%typecast( SegmentCount , 'int32' )-1
                        app.GlobalTranslation = app.MyClient.GetSegmentGlobalTranslation(SubjectName,viconObject);
                        app.GlobalEulerXYZ = app.MyClient.GetSegmentGlobalRotationEulerXYZ(SubjectName,viconObject);
                        app.v_pos_x = app.GlobalTranslation.Translation(1)/1000;
                        app.v_pos_y = app.GlobalTranslation.Translation(2)/1000;
                        app.v_pos_z = app.GlobalTranslation.Translation(3)/1000;
                        app.v_phi = app.GlobalEulerXYZ.Rotation(1) - deg2rad(90);
                        app.v_psi = app.GlobalEulerXYZ.Rotation(2);
                        app.v_theta = app.GlobalEulerXYZ.Rotation(3);
                        
                    end
                else
                    fprintf('Could not find Vicon Object.\n');
                end
            end
        end
        
        function showTelemetry(app)
            app.Altimetercm.Value=((app.v_pos_z*1000)-225);
            app.ArtificialHorizondeg.Roll = rad2deg(app.v_phi);
            app.ArtificialHorizondeg.Pitch = rad2deg(app.v_theta);
            app.HeadingdegHeadingIndicator.Heading = -rad2deg(app.v_psi);
            app.ThrustInputpwmRPMIndicator.RPM = app.pwmInput*100;
        end
        
        function y = checkIfEmpty(app,x)
            if(isempty(x))
                y = 0;
            else
                y=x;
            end
        end
        
        function checkControlType(app)
            if(app.TelemetryButton.Value)
                app.controlType = 1;
            elseif(app.AttAltManualPWMButton.Value)
                app.controlType = 2;
            elseif(app.AttAltEDFOFFButton.Value)
                app.controlType = 3;
            elseif(app.AttAltEDFONButton.Value)
                app.controlType = 4;
            elseif(app.ManualEDFButton.Value)
                app.controlType = 6;
            end
            
        end
        
        function y = sFunc(app,s,eps)
            temp=s;
            for i=1:length(s)
                if s(i)> eps
                    temp(i)=1;
                elseif s(i)< -eps 
                    temp(i)=-1;
                else
                    temp(i) = s(i)/eps;
                end
            end
            y=temp;
            
        end
        
        
        function deflector_angles = calculateAttitudeControl(app,measured_angles,des_angles,measured_omega,des_omega,lambda,A,B,eps)
            d = 0.0308;
            L = 0.1016;
            rho = 1.2041;
            Ad = 0.0016201;
            I = diag([0.05,0.04,0.04]);

            wSkew = [0 -measured_omega(3) measured_omega(2);
                    measured_omega(3) 0 -measured_omega(1);
                    -measured_omega(2) measured_omega(1) 0];
            
            d_euler = [measured_omega(1) + measured_omega(2)*sin(measured_angles(1))*tan(measured_angles(2)) + measured_omega(3)*cos(measured_angles(1))*tan(measured_angles(2));
                measured_omega(2)*cos(measured_angles(1)) - measured_omega(3)*sin(measured_angles(1));
                measured_omega(2)*sin(measured_angles(1))/cos(measured_angles(2)) + measured_omega(3)*cos(measured_angles(1))/cos(measured_angles(2))];
            
            H = [1,0,-sin(measured_angles(2));
                 0,cos(measured_angles(1)),sin(measured_angles(1))*cos(measured_angles(2));
                 0,-sin(measured_angles(1)),cos(measured_angles(1))*cos(measured_angles(2))];
            
            d_inv_H = [0 d_euler(1)*cos(measured_angles(1))*tan(measured_angles(1)) + sin(measured_angles(1))*d_euler(2)*(1 + tan(measured_angles(2))^2) -d_euler(1)*sin(measured_angles(1))*tan(measured_angles(1)) + cos(measured_angles(1))*d_euler(2)*(1 + tan(measured_angles(2))^2);
                       0 -d_euler(1)*sin(measured_angles(1)) -d_euler(1)*cos(measured_angles(1));
                       0 d_euler(1)*cos(measured_angles(1))/cos(measured_angles(2)) + sin(measured_angles(1))*d_euler(2)*sin(measured_angles(2))/(cos(measured_angles(2))*cos(measured_angles(2))) -d_euler(1)*sin(measured_angles(1))/cos(measured_angles(2)) + cos(measured_angles(1))*d_euler(2)*sin(measured_angles(2))/(cos(measured_angles(2))*cos(measured_angles(2)))];
            
            
            app.calc_error_angles = measured_angles - des_angles;
            app.calc_d_error_angles = d_euler - des_omega;
            
            app.calc_s_angles = lambda*app.calc_error_angles + app.calc_d_error_angles;
            
            app.calc_torque_angles = I*H*(-A*sat(app.calc_s_angles,eps) - B*app.calc_s_angles - lambda*app.calc_d_error_angles - d_inv_H*measured_omega) + wSkew*I*measured_omega;
            
            
            app.calc_force_angles = [-1/L 0 1/d;0 -1/L 1/d;1/L 0 1/d;0 1/L 1/d]*app.calc_torque_angles;
            
            deflector_angles = [(1/6.639)*asin((2*app.calc_force_angles(1))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)));
                                (1/6.639)*asin((2*app.calc_force_angles(2))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)));
                                (1/6.639)*asin((2*app.calc_force_angles(3))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)));
                                (1/6.639)*asin((2*app.calc_force_angles(4))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)))];
            
        end


        function getControllerVariables(app)
            app.lambda_phi = app.LambdaPhiEditField.Value; app.lambda_theta = app.LambdaThetaEditField.Value; app.lambda_psi = app.LambdaPsiEditField.Value;
            app.A_phi = app.APhiEditField.Value; app.A_theta = app.AThetaEditField.Value; app.A_psi = app.APsiEditField.Value;
            app.B_phi = app.BPhiEditField.Value; app.B_theta = app.BThetaEditField.Value; app.B_psi = app.BPsiEditField.Value;
            app.eps_phi = app.epsPhiEditField.Value; app.eps_theta = app.epsThetaEditField.Value; app.eps_psi = app.epsPsiEditField.Value;
        
            app.lambda_x = app.LambdaXEditField.Value; app.lambda_y = app.LambdaYEditField.Value; app.lambda_z = app.LambdaZEditField.Value;
            app.A_x = app.AXEditField.Value; app.A_y = app.AYEditField.Value; app.A_z = app.AZEditField.Value;
            app.B_x = app.BXEditField.Value; app.B_y = app.BYEditField.Value; app.B_z = app.BZEditField.Value;
            app.eps_x = app.epsXEditField.Value; app.eps_y = app.epsYEditField.Value; app.eps_z = app.epsZEditField.Value;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            app.DisconnectButton.Visible = 0;


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.BackgroundColor = 'y';
            app.ConnectButton.Text = 'Connecting';
            app.arduinoIP = app.ArduinoIPAddressEditField.Value;
            app.arduinoPort = app.ArduinoPortEditField.Value;
            app.viconIP = app.ViconIPAddressEditField.Value;
            app.viconPort = app.ViconPortEditField.Value;
            app.stationIP = app.StationIPAddressEditField.Value;
            app.stationPort = app.StationPortEditField.Value;
            app.viconObject = app.ViconObjectEditField.Value;
            
            
            fprintf('Arduino IP: %s \t Arduino Port: %d\n',app.arduinoIP,app.arduinoPort);
            fprintf('Vicon IP: %s   \t Vicon Port: %d\n',app.viconIP,app.viconPort);
            fprintf('Station IP: %s \t Station Port: %d\n',app.stationIP,app.stationPort);
            fprintf('Vicon Object: %s\n',app.viconObject);
            fprintf('Connecting to Arduino...\n');
            
            
            % Arduino Connection
            if(app.ArduinoCheckBox.Value == 1)
            app.udps = dsp.UDPSender('RemoteIPAddress',app.arduinoIP,'RemoteIPPort',app.arduinoPort);
            app.udpr = dsp.UDPReceiver('LocalIPPort',app.stationPort);
            setup(app.udpr);
            
            % Send message to arduino
            sData = 'StationON';
            app.udps(uint8(sData));
            
            % Waint for approval
            approved = false;
            i=0;
            fprintf('Waiting for Arduino .. ');
            while ~approved
                
                rData = app.udpr();
                disp(rData)
%                while(~isempty(rData))
%                    rData = app.udpr();
%                    
%                end
                rData = char(rData)';   
                app.DataStreamTextArea.Value = sprintf('%s',rData);
                if strcmp(rData,'ArduinoON')
                    approved = true;
                    fprintf('\n');
                    break;
                end
                app.udps(uint8(sData));
                
                fprintf(' .. ');
                pause(0.01);
                i=i+1;
%                if i >= 20
%                    fprintf('\n');                    
%                    error('Could not establish connection with Arduino');
%                end
            end
            
            
            fprintf('Station <-> Arduino Connection Established\n');
            app.vState = 1;
            %app.stateGauge.Value = app.vState;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%% Vicon Connection %%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if(app.ViconCheckBox.Value == 1)
            fprintf('Connecting to Vicon...\n');
            %Program Options
            TransmitMulticast = false;
            EnableHapticFeedbackTest = false;
            HapticOnList = {'ViconAP_001';'ViconAP_002'};
            SubjectFilterApplied = false;
            bPrintSkippedFrame = false;
            
            bReadCentroids = false;
            bReadRays = false;
            bTrajectoryIDs = false;
            axisMapping = 'ZUp';
            bLightweightSegment = false;
            EnableSubjectFilter = exist('subjects');
            
            HostName = app.viconIP;
            fprintf('Loading SDK...');
            addpath('..\dotNET');
            dssdkAssembly = which('ViconDataStreamSDK_DotNET.dll');
            if dssdkAssembly == ""
                [file,path] = uigetfile('*.dll')';
                if isequal(file,0)
                    fprintf('User canceled');
                    return
                else
                    dssdkAssembly = fullfile(path,file);
                end 
            end
            
            NET.addAssembly(dssdkAssembly);
            fprintf('done\n');
            % Make new client
            app.MyClient = ViconDataStreamSDK.DotNET.Client();
            fprintf('Connecting to .. ');
            i = 0;
            while ~app.MyClient.IsConnected().Connected
                app.MyClient.Connect(HostName);
                fprintf(' .. ');
                pause(0.5);
                i=i+1;
                if i>=20
                    fprintf('\n');
                    error('Could no establish connection with Vicon.')
                end
            end
            fprintf('\n');
            
            fprintf('Station <-> Vicon Connection Established\n')
            
            app.MyClient.EnableSegmentData;
            app.MyClient.SetBufferSize(1);
            app.MyClient.SetStreamMode( ViconDataStreamSDK.DotNET.StreamMode.ClientPull );
            app.MyClient.SetAxisMapping(ViconDataStreamSDK.DotNET.Direction.Forward, ...
                                    ViconDataStreamSDK.DotNET.Direction.Left, ...
                                    ViconDataStreamSDK.DotNET.Direction.Up);
            %}
            end
            fprintf('Connection Successful!\n')
            app.flagConnected = 1;
            app.ConnectButton.BackgroundColor = 'g';
            app.ConnectButton.Text = 'Connected';
            app.DisconnectButton.Visible = 1;
            app.DisconnectButton.Value = 0;
            
            %app.TurnOnButton.Visible = 1;
            %app.TurnOnButton.Value = 0;
            %app.AttitudeOnButton.Visible = 1;


        end

        % Value changed function: DisconnectButton
        function DisconnectButtonValueChanged(app, event)
            value = app.DisconnectButton.Value;
            if(value == 1)
                sData = 'Disconnect';
                app.udps(uint8(sData));
                release(app.udps);
                release(app.udpr);
                app.ConnectButton.BackgroundColor = '#E8E8E8';
                app.ConnectButton.Text = 'Connect';
                app.DisconnectButton.Visible = 0;
                app.DisconnectButton.Value = 0;
                app.DataStreamTextArea.Value = sprintf('Arduino Disconnected\n');
            end 
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if(app.flagConnected)
                time = cputime;
                fileName = sprintf('%s_%f_flight_data.txt',date,time);
                fileID = fopen(fileName,'w');
                
                while(value == 1)
                    tic;
                    
                    app.viconReceiveData(app.viconObject);
                    checkControlType(app);
                    app.setAlt = app.SetAltutudemEditField.Value;
                    app.pw_des = app.PWSlider.Value;
                    getControllerVariables(app);
                    % 
                    sData = sprintf('%d,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%3.3f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.4f,%1.4f,%1.4f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.4f,%1.4f,%1.4f',app.controlType,app.v_pos_x,app.v_pos_y,app.v_pos_z,app.v_phi,app.v_theta,app.v_psi,app.x_des,app.y_des,app.setAlt,app.pw_des,app.lambda_phi,app.lambda_theta,app.lambda_psi,app.A_phi,app.A_theta,app.A_psi,app.B_phi,app.B_theta,app.B_psi,app.eps_phi,app.eps_theta,app.eps_psi,app.lambda_x,app.lambda_y,app.lambda_z,app.A_x,app.A_y,app.A_z,app.B_x,app.B_y,app.B_z,app.eps_x,app.eps_y,app.eps_z);
                    
                    %disp(sData);
                    
                    app.udps(uint8(sData));
                    rData = app.udpr();
                    j=0;
                    while(isempty(rData))
                        rData = app.udpr();
                        if j>=1
                            break;
                        end
                        j = j+1;
                    end
                    
                    if(~isempty(rData))
                        data = sprintf('%f,%s',(cputime-app.onTime),rData);
                        data = string(data);
                        fprintf(fileID,'%s,%s\n',data,sData);
                        app.DataStreamTextArea.Value = data; 
                        data = strsplit(data,',');
                        data = double(data);
                        
                        if(length(data)==38)
                            
                            app.r_phi = data(2);
                            app.r_theta = data(3);
                            app.r_psi = data(4);
                            
                            app.r_des_phi = data(5);
                            app.r_des_theta = data(6);
                            app.r_des_psi = data(7);
        
                            app.r_w1 = data(8);
                            app.r_w2 = data(9);
                            app.r_w3 = data(10);
        
                            app.r_des_w1 = data(11);
                            app.r_des_w2 = data(12);
                            app.r_des_w3 = data(13);
        
                            app.r_lambda_phi = data(14);
                            app.r_lambda_theta = data(15);
                            app.r_lambda_psi = data(16);
        
                            app.r_A_phi =data(17);
                            app.r_A_theta =data(18);
                            app.r_A_psi =data(19);
        
                            app.r_B_phi =data(20);
                            app.r_B_theta =data(21);
                            app.r_B_psi =data(22);
        
                            app.r_eps_phi =data(23);
                            app.r_eps_theta =data(24);
                            app.r_eps_psi =data(25);
                            
                            app.r_z = data(26);
                            app.r_des_z = data(27);
                            app.r_d_z = data(28);
                            app.r_des_d_z = data(29);
        
                            app.r_lambda_z =data(30);
                            app.r_A_z = data(31);
                            app.r_B_z = data(32);
                            app.r_eps_z = data(33);
                            
                            app.r_uz = data(34);
                            app.r_g1 = data(35);
                            app.r_g2 = data(36);
                            app.r_g3 = data(37);
                            app.r_g4 = data(38);
                            
                            app.DataStreamTextArea.Value = num2str(data);
                            app.v_phi = app.r_phi;
                            app.v_theta = app.r_theta;
                            app.v_psi = -app.r_psi;
                            app.pwmInput = app.r_uz/47.93;
                            disp(app.pwmInput);
                            app.showTelemetry();
                            
                            app.calc_deflector_angles =calculateAttitudeControl(app,[app.r_phi;app.r_theta;app.r_psi],[app.r_des_phi;app.r_des_theta;app.r_des_psi],[app.r_w1;app.r_w2;app.r_w3],[app.r_des_w1;app.r_des_w2;app.r_des_w3],diag([app.r_lambda_phi,app.r_lambda_theta,app.r_lambda_psi]),diag([app.r_A_phi,app.r_A_theta,app.r_A_psi]),diag([app.r_B_phi,app.r_B_theta,app.r_B_psi]),[app.r_eps_phi;app.r_eps_theta;app.r_eps_psi]);
                            
                            app.ThrustTextArea.Value = sprintf('%2.3f',app.r_uz);
                            app.Deflector1TextArea.Value = sprintf('%1.4f',app.r_g1);
                            app.Deflector2TextArea.Value = sprintf('%1.4f',app.r_g2);
                            app.Deflector3TextArea.Value = sprintf('%1.4f',app.r_g3);
                            app.Deflector4TextArea.Value = sprintf('%1.4f',app.r_g4);
                            
                            app.Deflector1calcTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(1));
                            app.Deflector2calcTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(2));
                            app.Deflector3calcTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(3));
                            app.Deflector4calcTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(4));
                            
                            % Phi Data
                            app.LambdaPhiTextArea.Value = sprintf('%1.2f',app.r_lambda_phi);
                            app.APhiTextArea.Value = sprintf('%1.2f',app.r_A_phi);
                            app.BPhiTextArea.Value = sprintf('%1.2f',app.r_B_phi);
                            app.EpsPhiTextArea.Value = sprintf('%1.2f',app.r_eps_phi);
                            app.PhiErrorTextArea.Value = sprintf('%1.4f',app.calc_error_angles(1));
                            app.PhiRateErrorTextArea.Value = sprintf('%1.4f',app.calc_d_error_angles(1));
                            app.SPhiTextArea.Value = sprintf('%1.4f',app.calc_s_angles(1));
                            app.TorquePhiTextArea.Value = sprintf('%1.4f',app.calc_torque_angles(1));
                            
                            % Theta Data
                            app.LambdaThetaTextArea.Value = sprintf('%1.2f',app.r_lambda_theta);
                            app.AThetaTextArea.Value = sprintf('%1.2f',app.r_A_theta);
                            app.BThetaTextArea.Value = sprintf('%1.2f',app.r_B_theta);
                            app.EpsThetaTextArea.Value = sprintf('%1.2f',app.r_eps_theta);
                            app.ThetaErrorTextArea.Value = sprintf('%1.4f',app.calc_error_angles(2));
                            app.ThetaRateErrorTextArea.Value = sprintf('%1.4f',app.calc_d_error_angles(2));
                            app.SThetaTextArea.Value = sprintf('%1.4f',app.calc_s_angles(2));
                            app.TorqueThetaTextArea.Value = sprintf('%1.4f',app.calc_torque_angles(2));
                            
                            
                            % Psi Data
                            app.LambdaPsiTextArea.Value = sprintf('%1.2f',app.r_lambda_psi);
                            app.APsiTextArea.Value = sprintf('%1.2f',app.r_A_psi);
                            app.BPsiTextArea.Value = sprintf('%1.2f',app.r_B_psi);
                            app.EpsPsiTextArea.Value = sprintf('%1.2f',app.r_eps_psi);
                            app.PsiErrorTextArea.Value = sprintf('%1.4f',app.calc_error_angles(3));
                            app.PsiRateErrorTextArea.Value = sprintf('%1.4f',app.calc_d_error_angles(3));
                            app.SPsiTextArea.Value = sprintf('%1.4f',app.calc_s_angles(3));
                            app.TorquePsiTextArea.Value = sprintf('%1.4f',app.calc_torque_angles(3));
                            
                            app.PhiTextArea.Value = sprintf('%1.4f',app.r_phi);
                            app.ThetaTextArea.Value = sprintf('%1.4f',app.r_theta);
                            app.PsiTextArea.Value = sprintf('%1.4f',app.r_psi);

                            %}
                            
                        end
                    else
                        app.DataStreamTextArea.Value = 'No Data Received';
                    end
                    
                    t = toc;
                    if(t < app.loopTime)
                        pause(app.loopTime - t);
                    end
                    value = app.RunButton.Value;
                end
                fclose(fileID);
                app.DataStreamTextArea.Value = ""; 
                sData = sprintf('0,0');
                app.udps(uint8(sData));
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Ground Station Beta v0.1';
            app.UDPConnectionSettingsPanel = uipanel(app.UIFigure);
            app.UDPConnectionSettingsPanel.Title = 'UDP Connection Settings';
            app.ArduinoIPAddressEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ArduinoIPAddressEditFieldLabel.Text = 'Arduino IP Address';
            app.ArduinoIPAddressEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.ArduinoIPAddressEditField.Value = '192.168.1.36';
            app.ViconIPAddressEditField_2Label = uilabel(app.UDPConnectionSettingsPanel);
            app.ViconIPAddressEditField_2Label.Text = 'Vicon IP Address';
            app.ViconIPAddressEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.ViconIPAddressEditField.Value = '192.168.1.33';
            app.StationIPAddressEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.StationIPAddressEditFieldLabel.Text = 'Station IP Address';
            app.StationIPAddressEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.StationIPAddressEditField.Value = '192.168.1.35';
            app.ArduinoPortEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ArduinoPortEditFieldLabel.Text = 'Arduino Port';
            app.ArduinoPortEditField = uieditfield(app.UDPConnectionSettingsPanel, 'numeric');
            app.ArduinoPortEditField.Value = 2390;
            app.ViconPortLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ViconPortLabel.Text = 'Vicon Port';
            app.ViconPortEditField = uieditfield(app.UDPConnectionSettingsPanel, 'numeric');
            app.ViconPortEditField.Value = 1;
            app.StationPortEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.StationPortEditFieldLabel.Text = 'Station Port';
            app.StationPortEditField = uieditfield(app.UDPConnectionSettingsPanel, 'numeric');
            app.StationPortEditField.Value = 2380;
            app.ConnectButton = uibutton(app.UDPConnectionSettingsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ViconObjectEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ViconObjectEditFieldLabel.Text = 'Vicon Object';
            app.ViconObjectEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.ViconObjectEditField.Value = 'vtol22';
            app.ArduinoCheckBox = uicheckbox(app.UDPConnectionSettingsPanel);
            app.ArduinoCheckBox.Text = 'Arduino';
            app.ArduinoCheckBox.Value = true;
            app.ViconCheckBox = uicheckbox(app.UDPConnectionSettingsPanel);
            app.ViconCheckBox.Text = 'Vicon';
            app.ViconCheckBox.Value = true;
            app.DisconnectButton = uibutton(app.UDPConnectionSettingsPanel, 'state');
            app.DisconnectButton.ValueChangedFcn = createCallbackFcn(app, @DisconnectButtonValueChanged, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TelemetryPanel = uipanel(app.UIFigure);
            app.TelemetryPanel.Title = 'Telemetry';
            app.ArtificialHorizondegLabel = uilabel(app.TelemetryPanel);
            app.ArtificialHorizondegLabel.Text = 'ArtificialHorizon [deg]';
            app.ArtificialHorizondeg = uiaerohorizon(app.TelemetryPanel);
            app.AltimetercmLabel = uilabel(app.TelemetryPanel);
            app.AltimetercmLabel.Text = 'Altimeter [cm]';
            app.Altimetercm = uiaeroaltimeter(app.TelemetryPanel);
            app.HeadingdegHeadingIndicatorLabel = uilabel(app.TelemetryPanel);
            app.HeadingdegHeadingIndicatorLabel.Text = 'Heading [deg]';
            app.HeadingdegHeadingIndicator = uiaeroheading(app.TelemetryPanel);
            app.ThrustInputpwmRPMIndicatorLabel = uilabel(app.TelemetryPanel);
            app.ThrustInputpwmRPMIndicatorLabel.Text = 'Thrust Input [pwm]';
            app.ThrustInputpwmRPMIndicator = uiaerorpm(app.TelemetryPanel);
            app.DataStreamTextAreaLabel = uilabel(app.UIFigure);
            app.DataStreamTextAreaLabel.Text = 'Data Stream';
            app.DataStreamTextArea = uitextarea(app.UIFigure);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.RunButton = uibutton(app.ControlPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.SetAltutudemEditFieldLabel = uilabel(app.ControlPanel);
            app.SetAltutudemEditFieldLabel.Text = 'Set Altutude [m]';
            app.SetAltutudemEditField = uieditfield(app.ControlPanel, 'numeric');
            app.SetAltutudemEditField.Value = 0.7;
            app.PWSliderLabel = uilabel(app.ControlPanel);
            app.PWSliderLabel.Text = 'PW';
            app.PWSlider = uislider(app.ControlPanel);
            app.PWSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.PWSlider.Value = 1;
            app.ControlTypeButtonGroup = uibuttongroup(app.ControlPanel);
            app.ControlTypeButtonGroup.Title = 'Control Type';
            app.TelemetryButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.TelemetryButton.Text = 'Telemetry';
            app.TelemetryButton.Value = true;
            app.AttAltManualPWMButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.AttAltManualPWMButton.Text = 'Att, Alt, Manual PWM';
            app.AttAltEDFOFFButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.AttAltEDFOFFButton.Text = 'Att, Alt, EDF OFF';
            app.AttAltEDFONButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.AttAltEDFONButton.Text = 'Att, Alt, EDF ON';
            app.ManualEDFButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.ManualEDFButton.Text = 'Manual EDF';
            app.CalculatedInputsPanel = uipanel(app.UIFigure);
            app.CalculatedInputsPanel.Title = 'Calculated Inputs';
            app.DeflectorAnglesPanel = uipanel(app.CalculatedInputsPanel);
            app.DeflectorAnglesPanel.Title = 'Deflector Angles';
            app.Deflector1TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector1TextAreaLabel.Text = 'Deflector 1';
            app.Deflector1TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.Deflector3TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector3TextAreaLabel.Text = 'Deflector 3';
            app.Deflector3TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.Deflector4TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector4TextAreaLabel.Text = 'Deflector 4';
            app.Deflector4TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.Deflector2TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector2TextAreaLabel.Text = 'Deflector 2';
            app.Deflector2TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.ThrustTextArea_2Label = uilabel(app.CalculatedInputsPanel);
            app.ThrustTextArea_2Label.Text = 'Thrust';
            app.ThrustTextArea = uitextarea(app.CalculatedInputsPanel);
            app.SimulatedInputsPanel = uipanel(app.UIFigure);
            app.SimulatedInputsPanel.Title = 'Simulated Inputs';
            app.DeflectorAnglesPanel_2 = uipanel(app.SimulatedInputsPanel);
            app.DeflectorAnglesPanel_2.Title = 'Deflector Angles';
            app.Deflector1calcTextAreaLabel = uilabel(app.SimulatedInputsPanel);
            app.Deflector1calcTextAreaLabel.Text = 'Deflector 1 calc';
            app.Deflector1calcTextArea = uitextarea(app.SimulatedInputsPanel);
            app.Deflector2calcTextAreaLabel = uilabel(app.SimulatedInputsPanel);
            app.Deflector2calcTextAreaLabel.Text = 'Deflector 2 calc';
            app.Deflector2calcTextArea = uitextarea(app.SimulatedInputsPanel);
            app.Deflector3calcTextAreaLabel = uilabel(app.SimulatedInputsPanel);
            app.Deflector3calcTextAreaLabel.Text = 'Deflector 3 calc';
            app.Deflector3calcTextArea = uitextarea(app.SimulatedInputsPanel);
            app.Deflector4calcTextAreaLabel = uilabel(app.SimulatedInputsPanel);
            app.Deflector4calcTextAreaLabel.Text = 'Deflector 4 calc';
            app.Deflector4calcTextArea = uitextarea(app.SimulatedInputsPanel);
            app.PsiDataPanel = uipanel(app.UIFigure);
            app.PsiDataPanel.Title = 'Psi Data';
            app.PsiErrorTextAreaLabel = uilabel(app.PsiDataPanel);
            app.PsiErrorTextAreaLabel.Text = 'Psi Error';
            app.PsiErrorTextArea = uitextarea(app.PsiDataPanel);
            app.PsiRateErrorTextAreaLabel = uilabel(app.PsiDataPanel);
            app.PsiRateErrorTextAreaLabel.Text = 'Psi Rate Error';
            app.PsiRateErrorTextArea = uitextarea(app.PsiDataPanel);
            app.SPsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.SPsiTextAreaLabel.Text = 'S Psi';
            app.SPsiTextArea = uitextarea(app.PsiDataPanel);
            app.TorquePsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.TorquePsiTextAreaLabel.Text = 'Torque Psi';
            app.TorquePsiTextArea = uitextarea(app.PsiDataPanel);
            app.PsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.PsiTextAreaLabel.Text = 'Psi';
            app.PsiTextArea = uitextarea(app.PsiDataPanel);
            app.LambdaPsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.LambdaPsiTextAreaLabel.Text = 'Lambda Psi';
            app.LambdaPsiTextArea = uitextarea(app.PsiDataPanel);
            app.APsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.APsiTextAreaLabel.Text = 'A Psi';
            app.APsiTextArea = uitextarea(app.PsiDataPanel);
            app.BPsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.BPsiTextAreaLabel.Text = 'B Psi';
            app.BPsiTextArea = uitextarea(app.PsiDataPanel);
            app.EpsPsiTextAreaLabel = uilabel(app.PsiDataPanel);
            app.EpsPsiTextAreaLabel.Text = 'Eps Psi';
            app.EpsPsiTextArea = uitextarea(app.PsiDataPanel);
            app.ThetaDataPanel = uipanel(app.UIFigure);
            app.ThetaDataPanel.Title = 'Theta Data';
            app.ThetaErrorTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.ThetaErrorTextAreaLabel.Text = 'Theta Error';
            app.ThetaErrorTextArea = uitextarea(app.ThetaDataPanel);
            app.ThetaRateErrorTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.ThetaRateErrorTextAreaLabel.Text = 'Theta Rate Error';
            app.ThetaRateErrorTextArea = uitextarea(app.ThetaDataPanel);
            app.SThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.SThetaTextAreaLabel.Text = 'S Theta';
            app.SThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.TorqueThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.TorqueThetaTextAreaLabel.Text = 'Torque Theta';
            app.TorqueThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.ThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.ThetaTextAreaLabel.Text = 'Theta';
            app.ThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.LambdaThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.LambdaThetaTextAreaLabel.Text = 'Lambda Theta';
            app.LambdaThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.AThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.AThetaTextAreaLabel.Text = 'A Theta';
            app.AThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.BThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.BThetaTextAreaLabel.Text = 'B Theta';
            app.BThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.EpsThetaTextAreaLabel = uilabel(app.ThetaDataPanel);
            app.EpsThetaTextAreaLabel.Text = 'Eps Theta';
            app.EpsThetaTextArea = uitextarea(app.ThetaDataPanel);
            app.PsiDataPanel_2 = uipanel(app.UIFigure);
            app.PsiDataPanel_2.Title = 'Psi Data';
            app.PhiErrorTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.PhiErrorTextAreaLabel.Text = 'Phi Error';
            app.PhiErrorTextArea = uitextarea(app.PsiDataPanel_2);
            app.PhiRateErrorTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.PhiRateErrorTextAreaLabel.Text = 'Phi Rate Error';
            app.PhiRateErrorTextArea = uitextarea(app.PsiDataPanel_2);
            app.SPhiTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.SPhiTextAreaLabel.Text = 'S Phi';
            app.SPhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.TorquePhiTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.TorquePhiTextAreaLabel.Text = 'Torque Phi';
            app.TorquePhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.PhiTextArea_2Label = uilabel(app.PsiDataPanel_2);
            app.PhiTextArea_2Label.Text = 'Phi';
            app.PhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.LambdaPhiTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.LambdaPhiTextAreaLabel.Text = 'Lambda Phi';
            app.LambdaPhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.APhiTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.APhiTextAreaLabel.Text = 'A Phi';
            app.APhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.BPhiTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.BPhiTextAreaLabel.Text = 'B Phi';
            app.BPhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.EpsPhiTextAreaLabel = uilabel(app.PsiDataPanel_2);
            app.EpsPhiTextAreaLabel.Text = 'Eps Phi';
            app.EpsPhiTextArea = uitextarea(app.PsiDataPanel_2);
            app.ControllerVariablesPanel = uipanel(app.UIFigure);
            app.ControllerVariablesPanel.Title = 'Controller Variables';
            app.HorizontalPositionPanel = uipanel(app.ControllerVariablesPanel);
            app.HorizontalPositionPanel.Title = 'Horizontal Position';
            app.LambdaXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.LambdaXEditFieldLabel.Text = 'Lambda X';
            app.LambdaXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.LambdaXEditField.Value = 1;
            app.AXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.AXEditFieldLabel.Text = 'A X';
            app.AXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.AXEditField.Value = 1;
            app.BXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.BXEditFieldLabel.Text = 'B X';
            app.BXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.BXEditField.Value = 1;
            app.epsXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.epsXEditFieldLabel.Text = 'eps X';
            app.epsXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.epsXEditField.Value = 0.1;
            app.LambdaYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.LambdaYEditFieldLabel.Text = 'Lambda Y';
            app.LambdaYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.LambdaYEditField.Value = 1;
            app.AYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.AYEditFieldLabel.Text = 'A Y';
            app.AYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.AYEditField.Value = 1;
            app.BYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.BYEditFieldLabel.Text = 'B Y';
            app.BYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.BYEditField.Value = 1;
            app.epsYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.epsYEditFieldLabel.Text = 'eps Y';
            app.epsYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.epsYEditField.Value = 0.1;
            app.AltitudePanel = uipanel(app.ControllerVariablesPanel);
            app.AltitudePanel.Title = 'Altitude';
            app.LambdaZEditFieldLabel = uilabel(app.AltitudePanel);
            app.LambdaZEditFieldLabel.Text = 'Lambda Z';
            app.LambdaZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.LambdaZEditField.Value = 0.3;
            app.AZEditFieldLabel = uilabel(app.AltitudePanel);
            app.AZEditFieldLabel.Text = 'A Z';
            app.AZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.AZEditField.Value = 0.1;
            app.BZEditFieldLabel = uilabel(app.AltitudePanel);
            app.BZEditFieldLabel.Text = 'B Z';
            app.BZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.BZEditField.Value = 0.1;
            app.epsZEditFieldLabel = uilabel(app.AltitudePanel);
            app.epsZEditFieldLabel.Text = 'eps Z';
            app.epsZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.epsZEditField.Value = 1;
            app.AttitudePanel = uipanel(app.ControllerVariablesPanel);
            app.AttitudePanel.Title = 'Attitude';
            app.LambdaPhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.LambdaPhiEditFieldLabel.Text = 'Lambda Phi';
            app.LambdaPhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.LambdaPhiEditField.Value = 5;
            app.APhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.APhiEditFieldLabel.Text = 'A Phi';
            app.APhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BPhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.BPhiEditFieldLabel.Text = 'B Phi';
            app.BPhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BPhiEditField.Value = 1;
            app.epsPhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.epsPhiEditFieldLabel.Text = 'eps Phi';
            app.epsPhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.epsPhiEditField.Value = 0.001;
            app.LambdaThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.LambdaThetaEditFieldLabel.Text = 'Lambda Theta';
            app.LambdaThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.LambdaThetaEditField.Value = 5;
            app.AThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.AThetaEditFieldLabel.Text = 'A Theta';
            app.AThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.BThetaEditFieldLabel.Text = 'B Theta';
            app.BThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BThetaEditField.Value = 1;
            app.epsThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.epsThetaEditFieldLabel.Text = 'eps Theta';
            app.epsThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.epsThetaEditField.Value = 0.001;
            app.LambdaPsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.LambdaPsiEditFieldLabel.Text = 'Lambda Psi';
            app.LambdaPsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.LambdaPsiEditField.Value = 3;
            app.APsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.APsiEditFieldLabel.Text = 'A Psi';
            app.APsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BPsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.BPsiEditFieldLabel.Text = 'B Psi';
            app.BPsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BPsiEditField.Value = 1;
            app.epsPsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.epsPsiEditFieldLabel.Text = 'eps Psi';
            app.epsPsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.epsPsiEditField.Value = 0.001;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ground_station_flight_software_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end