classdef ground_station_flight_software_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.EditField
        UDPConnectionSettingsPanel      matlab.ui.container.Panel
        ArduinoIPAddressEditFieldLabel  matlab.ui.control.Label
        ArduinoIPAddressEditField       matlab.ui.control.EditField
        ViconIPAddressEditField_2Label  matlab.ui.control.Label
        ViconIPAddressEditField         matlab.ui.control.EditField
        StationIPAddressEditFieldLabel  matlab.ui.control.Label
        StationIPAddressEditField       matlab.ui.control.EditField
        ArduinoPortEditFieldLabel       matlab.ui.control.Label
        ArduinoPortEditField            matlab.ui.control.NumericEditField
        ViconPortLabel                  matlab.ui.control.Label
        ViconPortEditField              matlab.ui.control.NumericEditField
        StationPortEditFieldLabel       matlab.ui.control.Label
        StationPortEditField            matlab.ui.control.NumericEditField
        ConnectButton                   matlab.ui.control.Button
        ViconObjectEditFieldLabel       matlab.ui.control.Label
        ViconObjectEditField            matlab.ui.control.EditField
        ArduinoCheckBox                 matlab.ui.control.CheckBox
        ViconCheckBox                   matlab.ui.control.CheckBox
        DisconnectButton                matlab.ui.control.StateButton
        TelemetryPanel                  matlab.ui.container.Panel
        ArtificialHorizondegLabel       matlab.ui.control.Label
        ArtificialHorizondeg            Aero.ui.control.ArtificialHorizon
        AltimetercmLabel                matlab.ui.control.Label
        Altimetercm                     Aero.ui.control.Altimeter
        HeadingdegHeadingIndicatorLabel  matlab.ui.control.Label
        HeadingdegHeadingIndicator      Aero.ui.control.HeadingIndicator
        ThrustInputpwmRPMIndicatorLabel  matlab.ui.control.Label
        ThrustInputpwmRPMIndicator      Aero.ui.control.RPMIndicator
        DataStreamTextAreaLabel         matlab.ui.control.Label
        DataStreamTextArea              matlab.ui.control.TextArea
        ControlPanel                    matlab.ui.container.Panel
        RunButton                       matlab.ui.control.StateButton
        SetAltutudemEditFieldLabel      matlab.ui.control.Label
        SetAltutudemEditField           matlab.ui.control.NumericEditField
        PWSliderLabel                   matlab.ui.control.Label
        PWSlider                        matlab.ui.control.Slider
        ControlTypeButtonGroup          matlab.ui.container.ButtonGroup
        TelemetryButton                 matlab.ui.control.RadioButton
        PositionButton                  matlab.ui.control.RadioButton
        AttAltEDFOFFButton              matlab.ui.control.RadioButton
        AttitudeandAltitudeButton       matlab.ui.control.RadioButton
        OnlyEDFButton                   matlab.ui.control.RadioButton
        SetXPosmEditFieldLabel          matlab.ui.control.Label
        SetXPosmEditField               matlab.ui.control.NumericEditField
        SetYPosmEditFieldLabel          matlab.ui.control.Label
        SetYPosmEditField               matlab.ui.control.NumericEditField
        ControllerOutputFromVehiclePanel  matlab.ui.container.Panel
        DeflectorAnglesPanel            matlab.ui.container.Panel
        Deflector1TextAreaLabel         matlab.ui.control.Label
        Deflector1TextArea              matlab.ui.control.TextArea
        Deflector3TextAreaLabel         matlab.ui.control.Label
        Deflector3TextArea              matlab.ui.control.TextArea
        Deflector4TextAreaLabel         matlab.ui.control.Label
        Deflector4TextArea              matlab.ui.control.TextArea
        Deflector2TextAreaLabel         matlab.ui.control.Label
        Deflector2TextArea              matlab.ui.control.TextArea
        ThrustTextArea_2Label           matlab.ui.control.Label
        ThrustTextArea                  matlab.ui.control.TextArea
        DesPhiTextAreaLabel             matlab.ui.control.Label
        DesPhiTextArea                  matlab.ui.control.TextArea
        DesThetaTextAreaLabel           matlab.ui.control.Label
        DesThetaTextArea                matlab.ui.control.TextArea
        ControllerVariablesPanel        matlab.ui.container.Panel
        HorizontalPositionPanel         matlab.ui.container.Panel
        LambdaXEditFieldLabel           matlab.ui.control.Label
        LambdaXEditField                matlab.ui.control.NumericEditField
        AXEditFieldLabel                matlab.ui.control.Label
        AXEditField                     matlab.ui.control.NumericEditField
        BXEditFieldLabel                matlab.ui.control.Label
        BXEditField                     matlab.ui.control.NumericEditField
        epsXEditFieldLabel              matlab.ui.control.Label
        epsXEditField                   matlab.ui.control.NumericEditField
        LambdaYEditFieldLabel           matlab.ui.control.Label
        LambdaYEditField                matlab.ui.control.NumericEditField
        AYEditFieldLabel                matlab.ui.control.Label
        AYEditField                     matlab.ui.control.NumericEditField
        BYEditFieldLabel                matlab.ui.control.Label
        BYEditField                     matlab.ui.control.NumericEditField
        epsYEditFieldLabel              matlab.ui.control.Label
        epsYEditField                   matlab.ui.control.NumericEditField
        AltitudePanel                   matlab.ui.container.Panel
        LambdaZEditFieldLabel           matlab.ui.control.Label
        LambdaZEditField                matlab.ui.control.NumericEditField
        AZEditFieldLabel                matlab.ui.control.Label
        AZEditField                     matlab.ui.control.NumericEditField
        BZEditFieldLabel                matlab.ui.control.Label
        BZEditField                     matlab.ui.control.NumericEditField
        epsZEditFieldLabel              matlab.ui.control.Label
        epsZEditField                   matlab.ui.control.NumericEditField
        AttitudePanel                   matlab.ui.container.Panel
        LambdaPhiEditFieldLabel         matlab.ui.control.Label
        LambdaPhiEditField              matlab.ui.control.NumericEditField
        APhiEditFieldLabel              matlab.ui.control.Label
        APhiEditField                   matlab.ui.control.NumericEditField
        BPhiEditFieldLabel              matlab.ui.control.Label
        BPhiEditField                   matlab.ui.control.NumericEditField
        epsPhiEditFieldLabel            matlab.ui.control.Label
        epsPhiEditField                 matlab.ui.control.NumericEditField
        LambdaThetaEditFieldLabel       matlab.ui.control.Label
        LambdaThetaEditField            matlab.ui.control.NumericEditField
        AThetaEditFieldLabel            matlab.ui.control.Label
        AThetaEditField                 matlab.ui.control.NumericEditField
        BThetaEditFieldLabel            matlab.ui.control.Label
        BThetaEditField                 matlab.ui.control.NumericEditField
        epsThetaEditFieldLabel          matlab.ui.control.Label
        epsThetaEditField               matlab.ui.control.NumericEditField
        LambdaPsiEditFieldLabel         matlab.ui.control.Label
        LambdaPsiEditField              matlab.ui.control.NumericEditField
        APsiEditFieldLabel              matlab.ui.control.Label
        APsiEditField                   matlab.ui.control.NumericEditField
        BPsiEditFieldLabel              matlab.ui.control.Label
        BPsiEditField                   matlab.ui.control.NumericEditField
        epsPsiEditFieldLabel            matlab.ui.control.Label
        epsPsiEditField                 matlab.ui.control.NumericEditField
        DeflectorAnglesPanel_3          matlab.ui.container.Panel
        Deflector1AngleGaugeLabel       matlab.ui.control.Label
        Deflector1AngleGauge            matlab.ui.control.SemicircularGauge
        Deflector2AngleGaugeLabel       matlab.ui.control.Label
        Deflector2AngleGauge            matlab.ui.control.SemicircularGauge
        Deflector3AngleGaugeLabel       matlab.ui.control.Label
        Deflector3AngleGauge            matlab.ui.control.SemicircularGauge
        Deflector4AngleGaugeLabel       matlab.ui.control.Label
        Deflector4AngleGauge            matlab.ui.control.SemicircularGauge
        DataPanel                       matlab.ui.container.Panel
        AttitudeandPositionPanel        matlab.ui.container.Panel
        PhiTextArea_2Label              matlab.ui.control.Label
        PhiTextArea                     matlab.ui.control.TextArea
        ThetaTextAreaLabel              matlab.ui.control.Label
        ThetaTextArea                   matlab.ui.control.TextArea
        PsiTextAreaLabel                matlab.ui.control.Label
        PsiTextArea                     matlab.ui.control.TextArea
        r_e1TextAreaLabel               matlab.ui.control.Label
        r_e1TextArea                    matlab.ui.control.TextArea
        r_e2TextAreaLabel               matlab.ui.control.Label
        r_e2TextArea                    matlab.ui.control.TextArea
        r_e3TextAreaLabel               matlab.ui.control.Label
        r_e3TextArea                    matlab.ui.control.TextArea
        IMUStatusPanel                  matlab.ui.container.Panel
        AccTextAreaLabel                matlab.ui.control.Label
        AccTextArea                     matlab.ui.control.TextArea
        GyroTextAreaLabel               matlab.ui.control.Label
        GyroTextArea                    matlab.ui.control.TextArea
        MagTextAreaLabel                matlab.ui.control.Label
        MagTextArea                     matlab.ui.control.TextArea
        SysTextAreaLabel                matlab.ui.control.Label
        SysTextArea                     matlab.ui.control.TextArea
        UpdateControllerGainsButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        viconObject % Description
        vState = 0; % State of the vehicle
        arduinoIP;
        arduinoPort;
        viconIP;
        viconPort;
        stationIP;
        stationPort;
        udps;
        udpr;
        
        MyClient
            
        sentData = "";
        
        % Vicon Data
        GlobalTranslation;
        GlobalEulerXYZ;
        v_pos_x = 0;
        v_pos_y = 0;
        v_pos_z = 0;
        v_phi = 0;
        v_theta = 0;
        v_psi = 0;
        pwmInput = 0;
        m_phi = 0;
        m_theta = 0;
        m_psi = 0;
        
        
        setAlt; % Altitude ref
        x_des = 0; % X pos ref
        y_des = 0; % Y pos ref
        pw_des = 0; % pwm user input
        
        
        flagConnected = 0;
        controlType = 0;
        
        
        loopTime = 0.05;
        
        onTime = cputime;
        
        lambda_phi = 0; lambda_theta = 0; lambda_psi = 0;
        A_phi = 0; A_theta = 0; A_psi = 0;
        B_phi = 0; B_theta = 0; B_psi = 0;
        eps_phi = 0; eps_theta = 0; eps_psi = 0;
        
        lambda_x = 0; lambda_y = 0; lambda_z = 0;
        A_x = 0; A_y = 0; A_z = 0;
        B_x = 0; B_y = 0; B_z = 0;
        eps_x = 0; eps_y = 0; eps_z = 0;
        
        % vehicle data
        r_x = 0;
        r_y = 0;
        r_z = 0;
        
        r_des_x = 0;
        r_des_y = 0;
        r_des_z = 0;
        
        r_d_x = 0;
        r_d_y = 0;
        r_d_z = 0;
        
        r_des_d_x = 0;
        r_des_d_y = 0;
        r_des_d_z = 0;
        
        r_phi = 0;
        r_theta = 0;
        r_psi = 0;
        
        r_des_phi = 0;
        r_des_theta = 0;
        r_des_psi = 0;
        
        r_w1 = 0;
        r_w2 = 0;
        r_w3 = 0;
        
        r_des_w1 = 0;
        r_des_w2 = 0;
        r_des_w3 = 0;
        
        r_lambda_phi = 0;
        r_lambda_theta = 0;
        r_lambda_psi = 0;
        
        r_A_phi =0;
        r_A_theta =0;
        r_A_psi =0;
        
        r_B_phi =0;
        r_B_theta =0;
        r_B_psi =0;
        
        r_eps_phi =0;
        r_eps_theta =0;
        r_eps_psi =0;
        
        r_lambda_z =0;
        r_A_z = 0;
        r_B_z = 0;
        r_eps_z = 0;
        
        r_uz = 0;
        r_g1 = 0;
        r_g2 = 0;
        r_g3 = 0;
        r_g4 = 0;
        
        calc_uz = 0;
        
        calc_error_angles = 0;
        calc_d_error_angles = 0;
        calc_s_angles = 0;
        calc_torque_angles = 0;
        calc_force_angles = 0;
        calc_deflector_angles = 0;
        
        accStatus = 0;
        magStatus = 0;
        gyrStatus = 0;
        sysStatus = 0;
        
        phi_buffer = zeros(1,11);
        t = 0:0.02:0.2;
        
        % Position Buffers
        x_pos_buff = zeros(1,11);
        y_pos_buff = zeros(1,11);
        
        
    end
    
    properties (Access = public)
        mData % Description
    end
    
    methods (Access = private)
        
        function viconReceiveData(app,viconObject)
            while   app.MyClient.GetFrame().Result ~= ViconDataStreamSDK.DotNET.Result.Success
                fprintf('.');
            end
            SubjectCount = app.MyClient.GetSubjectCount().SubjectCount;
            for SubjectIndex = 0 : typecast(SubjectCount,'int32') -1
                SubjectName = app.MyClient.GetSubjectName( typecast( SubjectIndex, 'uint32') ).SubjectName;
                %if strcmp(SubjectName,viconObject)
                if(SubjectName == app.viconObject)
                    SegmentCount = 1;
                    for SegmentIndex = 0:0%typecast( SegmentCount , 'int32' )-1
                        app.GlobalTranslation = app.MyClient.GetSegmentGlobalTranslation(SubjectName,viconObject);
                        app.GlobalEulerXYZ = app.MyClient.GetSegmentGlobalRotationEulerXYZ(SubjectName,viconObject);
                        app.v_pos_x = ((app.GlobalTranslation.Translation(1)/1000) - (-0.247239));
                        app.v_pos_y = -((app.GlobalTranslation.Translation(2)/1000) - 0.140251);
                        app.v_pos_z = app.GlobalTranslation.Translation(3)/1000;
                        app.v_phi = app.GlobalEulerXYZ.Rotation(1) - deg2rad(90);
                        app.v_theta = app.GlobalEulerXYZ.Rotation(2);
                        app.v_psi = -app.GlobalEulerXYZ.Rotation(3);
                    end
                else
                    fprintf('Could not find Vicon Object.\n');
                end
            end
        end
        
        function showTelemetry(app)
            app.Altimetercm.Value=((app.v_pos_z*1000)-225);
            app.ArtificialHorizondeg.Roll = rad2deg(app.v_phi);
            app.ArtificialHorizondeg.Pitch = rad2deg(app.v_theta);
            app.HeadingdegHeadingIndicator.Heading = -rad2deg(app.v_psi);
            app.ThrustInputpwmRPMIndicator.RPM = app.pwmInput*100;
        end
        
        function y = checkIfEmpty(app,x)
            if(isempty(x))
                y = 0;
            else
                y=x;
            end
        end
        
        function checkControlType(app)
            if(app.TelemetryButton.Value) % Telemetry
                app.controlType = 1;
            elseif(app.PositionButton.Value) % Position Control
                app.controlType = 2;
            elseif(app.AttAltEDFOFFButton.Value)
                app.controlType = 3;
            elseif(app.AttitudeandAltitudeButton.Value) % Attitude and Altitude
                app.controlType = 4;
            elseif(app.OnlyEDFButton.Value) % Just EDF
                app.controlType = 6;
            end
            
        end
        
        function y = sFunc(app,s,eps)
            temp=s;
            for i=1:length(s)
                if s(i)> eps
                    temp(i)=1;
                elseif s(i)< -eps 
                    temp(i)=-1;
                else
                    temp(i) = s(i)/eps;
                end
            end
            y=temp;
            
        end
        
        
        function deflector_angles = calculateAttitudeControl(app,measured_angles,des_angles,measured_omega,des_omega,lambda,A,B,eps)
            d = 0.0308;
            L = 0.1016;
            rho = 1.2041;
            Ad = 0.0016201;
            I = diag([0.05,0.04,0.04]);

            wSkew = [0 -measured_omega(3) measured_omega(2);
                    measured_omega(3) 0 -measured_omega(1);
                    -measured_omega(2) measured_omega(1) 0];
            
            d_euler = [measured_omega(1) + measured_omega(2)*sin(measured_angles(1))*tan(measured_angles(2)) + measured_omega(3)*cos(measured_angles(1))*tan(measured_angles(2));
                measured_omega(2)*cos(measured_angles(1)) - measured_omega(3)*sin(measured_angles(1));
                measured_omega(2)*sin(measured_angles(1))/cos(measured_angles(2)) + measured_omega(3)*cos(measured_angles(1))/cos(measured_angles(2))];
            
            H = [1,0,-sin(measured_angles(2));
                 0,cos(measured_angles(1)),sin(measured_angles(1))*cos(measured_angles(2));
                 0,-sin(measured_angles(1)),cos(measured_angles(1))*cos(measured_angles(2))];
            
            d_inv_H = [0 d_euler(1)*cos(measured_angles(1))*tan(measured_angles(1)) + sin(measured_angles(1))*d_euler(2)*(1 + tan(measured_angles(2))^2) -d_euler(1)*sin(measured_angles(1))*tan(measured_angles(1)) + cos(measured_angles(1))*d_euler(2)*(1 + tan(measured_angles(2))^2);
                       0 -d_euler(1)*sin(measured_angles(1)) -d_euler(1)*cos(measured_angles(1));
                       0 d_euler(1)*cos(measured_angles(1))/cos(measured_angles(2)) + sin(measured_angles(1))*d_euler(2)*sin(measured_angles(2))/(cos(measured_angles(2))*cos(measured_angles(2))) -d_euler(1)*sin(measured_angles(1))/cos(measured_angles(2)) + cos(measured_angles(1))*d_euler(2)*sin(measured_angles(2))/(cos(measured_angles(2))*cos(measured_angles(2)))];
            
            
            app.calc_error_angles = measured_angles - des_angles;
            app.calc_d_error_angles = d_euler - des_omega;
            
            app.calc_s_angles = lambda*app.calc_error_angles + app.calc_d_error_angles;
            
            app.calc_torque_angles = I*H*(-A*sat(app.calc_s_angles,eps) - B*app.calc_s_angles - lambda*app.calc_d_error_angles - d_inv_H*measured_omega) + wSkew*I*measured_omega;
            
            
            app.calc_force_angles = [-1/L 0 1/d;0 -1/L 1/d;1/L 0 1/d;0 1/L 1/d]*app.calc_torque_angles;
            
            deflector_angles = [(1/6.639)*asin((2*app.calc_force_angles(1))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)));
                                (1/6.639)*asin((2*app.calc_force_angles(2))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)));
                                (1/6.639)*asin((2*app.calc_force_angles(3))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)));
                                (1/6.639)*asin((2*app.calc_force_angles(4))/(1.143*rho*Ad*(48.648*(app.pwmInput*100) - 131.08)))];
            
        end


        function getControllerVariables(app)
            app.lambda_phi = app.LambdaPhiEditField.Value; app.lambda_theta = app.LambdaThetaEditField.Value; app.lambda_psi = app.LambdaPsiEditField.Value;
            app.A_phi = app.APhiEditField.Value; app.A_theta = app.AThetaEditField.Value; app.A_psi = app.APsiEditField.Value;
            app.B_phi = app.BPhiEditField.Value; app.B_theta = app.BThetaEditField.Value; app.B_psi = app.BPsiEditField.Value;
            app.eps_phi = app.epsPhiEditField.Value; app.eps_theta = app.epsThetaEditField.Value; app.eps_psi = app.epsPsiEditField.Value;
        
            app.lambda_x = app.LambdaXEditField.Value; app.lambda_y = app.LambdaYEditField.Value; app.lambda_z = app.LambdaZEditField.Value;
            app.A_x = app.AXEditField.Value; app.A_y = app.AYEditField.Value; app.A_z = app.AZEditField.Value;
            app.B_x = app.BXEditField.Value; app.B_y = app.BYEditField.Value; app.B_z = app.BZEditField.Value;
            app.eps_x = app.epsXEditField.Value; app.eps_y = app.epsYEditField.Value; app.eps_z = app.epsZEditField.Value;
            
        end
        
        function plotPositon(app)
            len = length(app.y_pos_buff);
            
            for i=1:1:len-1
                app.x_pos_buff(i) = app.x_pos_buff(i+1);
                app.y_pos_buff(i) = app.y_pos_buff(i+1);
            end
            app.x_pos_buff(len) = app.v_pos_x;
            app.y_pos_buff(len) = app.v_pos_y;
            
            
            plot(app.UIAxes,app.y_pos_buff,app.x_pos_buff);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.y_pos_buff(len),app.x_pos_buff(len),'o','MarkerSize',5,'Color','r','MarkerFaceColor','r');
            plot(app.UIAxes,app.y_des,app.x_des,'o','MarkerSize',14,'Color','b');
            hold(app.UIAxes,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            app.DisconnectButton.Visible = 0;


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.BackgroundColor = 'y';
            app.ConnectButton.Text = 'Connecting';
            app.arduinoIP = app.ArduinoIPAddressEditField.Value;
            app.arduinoPort = app.ArduinoPortEditField.Value;
            app.viconIP = app.ViconIPAddressEditField.Value;
            app.viconPort = app.ViconPortEditField.Value;
            app.stationIP = app.StationIPAddressEditField.Value;
            app.stationPort = app.StationPortEditField.Value;
            app.viconObject = app.ViconObjectEditField.Value;
            
            
            fprintf('Arduino IP: %s \t Arduino Port: %d\n',app.arduinoIP,app.arduinoPort);
            fprintf('Vicon IP: %s   \t Vicon Port: %d\n',app.viconIP,app.viconPort);
            fprintf('Station IP: %s \t Station Port: %d\n',app.stationIP,app.stationPort);
            fprintf('Vicon Object: %s\n',app.viconObject);
            fprintf('Connecting to Arduino...\n');
            
            
            % Arduino Connection
            if(app.ArduinoCheckBox.Value == 1)
            app.udps = dsp.UDPSender('RemoteIPAddress',app.arduinoIP,'RemoteIPPort',app.arduinoPort);
            app.udpr = dsp.UDPReceiver('LocalIPPort',app.stationPort);
            setup(app.udpr);
            
            % Send message to arduino
            sData = 'StationON';
            app.udps(uint8(sData));
            
            % Waint for approval
            approved = false;
            i=0;
            fprintf('Waiting for Arduino .. ');
            while ~approved
                
                rData = app.udpr();
                
                disp(rData)
%                while(~isempty(rData))
%                    rData = app.udpr();
%                    
%                end
                rData = char(rData)';   
                app.DataStreamTextArea.Value = sprintf('%s',rData);
                if strcmp(rData,'ArduinoON')
                    approved = true;
                    fprintf('\n');
                    break;
                end
                app.udps(uint8(sData));
                
                fprintf(' .. ');
                pause(0.01);
                i=i+1;
%                if i >= 20
%                    fprintf('\n');                    
%                    error('Could not establish connection with Arduino');
%                end
            end
            
            
            fprintf('Station <-> Arduino Connection Established\n');
            app.vState = 1;
            
            release(app.udpr);
            release(app.udps);
            %app.stateGauge.Value = app.vState;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%% Vicon Connection %%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if(app.ViconCheckBox.Value == 1)
            fprintf('Connecting to Vicon...\n');
            %Program Options
            TransmitMulticast = false;
            EnableHapticFeedbackTest = false;
            HapticOnList = {'ViconAP_001';'ViconAP_002'};
            SubjectFilterApplied = false;
            bPrintSkippedFrame = false;
            
            bReadCentroids = false;
            bReadRays = false;
            bTrajectoryIDs = false;
            axisMapping = 'ZUp';
            bLightweightSegment = false;
            EnableSubjectFilter = exist('subjects');
            
            HostName = app.viconIP;
            fprintf('Loading SDK...');
            addpath('..\dotNET');
            dssdkAssembly = which('ViconDataStreamSDK_DotNET.dll');
            if dssdkAssembly == ""
                [file,path] = uigetfile('*.dll')';
                if isequal(file,0)
                    fprintf('User canceled');
                    return
                else
                    dssdkAssembly = fullfile(path,file);
                end 
            end
            
            NET.addAssembly(dssdkAssembly);
            fprintf('done\n');
            % Make new client
            app.MyClient = ViconDataStreamSDK.DotNET.Client();
            fprintf('Connecting to .. ');
            i = 0;
            while ~app.MyClient.IsConnected().Connected
                app.MyClient.Connect(HostName);
                fprintf(' .. ');
                pause(0.5);
                i=i+1;
                if i>=20
                    fprintf('\n');
                    error('Could no establish connection with Vicon.')
                end
            end
            fprintf('\n');
            
            fprintf('Station <-> Vicon Connection Established\n')
            
            app.MyClient.EnableSegmentData;
            app.MyClient.SetBufferSize(1);
            app.MyClient.SetStreamMode( ViconDataStreamSDK.DotNET.StreamMode.ClientPull );
            app.MyClient.SetAxisMapping(ViconDataStreamSDK.DotNET.Direction.Forward, ...
                                    ViconDataStreamSDK.DotNET.Direction.Left, ...
                                    ViconDataStreamSDK.DotNET.Direction.Up);
            %}
            end
            fprintf('Connection Successful!\n')
            app.flagConnected = 1;
            app.ConnectButton.BackgroundColor = 'g';
            app.ConnectButton.Text = 'Connected';
            app.DisconnectButton.Visible = 1;
            app.DisconnectButton.Value = 0;
            
            %app.TurnOnButton.Visible = 1;
            %app.TurnOnButton.Value = 0;
            %app.AttitudeOnButton.Visible = 1;


        end

        % Value changed function: DisconnectButton
        function DisconnectButtonValueChanged(app, event)
            value = app.DisconnectButton.Value;
            if(value == 1)
                sData = 'Disconnect';
                app.udps(uint8(sData));
                release(app.udps);
                
                app.ConnectButton.BackgroundColor = '#E8E8E8';
                app.ConnectButton.Text = 'Connect';
                app.DisconnectButton.Visible = 0;
                app.DisconnectButton.Value = 0;
                app.DataStreamTextArea.Value = sprintf('Arduino Disconnected\n');
            end 
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if(app.flagConnected)
                time = cputime;
                fileName = sprintf('%s_%f_flight_data.txt',date,time);
                fileID = fopen(fileName,'w');
                app.udps = dsp.UDPSender('RemoteIPAddress',app.arduinoIP,'RemoteIPPort',app.arduinoPort);
                app.udpr = dsp.UDPReceiver('LocalIPPort',app.stationPort);
                setup(app.udpr);
                
                timePosition = clock;
                
                while(value == 1)
                    
                    timeStart = clock;
                    
                    
                    % if it is time to send position data
                    if(etime(clock,timePosition) >= 0.2)
                        timePosition = clock;
                        disp(timePosition);
                        
                        if(app.ViconCheckBox.Value == 1)
                            app.viconReceiveData(app.viconObject);
                        end
                        checkControlType(app);
                    
                        app.setAlt = app.SetAltutudemEditField.Value;
                        app.x_des = app.SetXPosmEditField.Value;
                        app.y_des = app.SetYPosmEditField.Value;
                    
                        app.pw_des = app.PWSlider.Value;
                    
                        getControllerVariables(app);
                    
                        sData = sprintf('%1d,%2.3f,%2.3f,%1.3f,%2.3f,%2.3f,%1.3f,%3.3f,%2.4',app.controlType,app.v_pos_x,app.v_pos_y,app.v_pos_z,app.x_des,app.y_des,app.setAlt,app.pw_des,app.v_psi);
                        
                        app.udps(uint8(sData));
                        
                        app.plotPositon();
                        
                    end


                    rData = app.udpr();
                    
                    release(app.udpr);
                    
                    app.udpr = dsp.UDPReceiver('LocalIPPort',app.stationPort);
                    setup(app.udpr);
                    
                    
                    if(~isempty(rData))
                        data = sprintf('%f,%s',(cputime-app.onTime),rData);
                        data = string(data);
                        
                        sData = sprintf('%1d,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%3.3f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.4f,%1.4f,%1.4f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.4f,%1.4f,%1.4f',app.controlType,app.v_pos_x,app.v_pos_y,-app.v_pos_z,app.v_phi,app.v_theta,app.v_psi,app.x_des,app.y_des,-app.setAlt,app.pw_des,app.lambda_phi,app.lambda_theta,app.lambda_psi,app.A_phi,app.A_theta,app.A_psi,app.B_phi,app.B_theta,app.B_psi,app.eps_phi,app.eps_theta,app.eps_psi,app.lambda_x,app.lambda_y,app.lambda_z,app.A_x,app.A_y,app.A_z,app.B_x,app.B_y,app.B_z,app.eps_x,app.eps_y,app.eps_z);
                        app.sentData = sData;
                        
                        fprintf(fileID,'%s,%s\n',data,app.sentData);
                        
                        
                        app.DataStreamTextArea.Value = data; 
                        data = strsplit(data,',');
                        data = double(data);
                        %disp(data);
                        
                        if(length(data)==25)
                            
                            app.r_phi = data(2);
                            app.r_theta = data(3);
                            app.r_psi = data(4);
                            
                            app.r_des_phi = data(5);
                            app.r_des_theta = data(6);
                            app.r_des_psi = data(7);
        
                            app.r_w1 = data(8);
                            app.r_w2 = data(9);
                            app.r_w3 = data(10);
        
                            app.r_des_w1 = data(11);
                            app.r_des_w2 = data(12);
                            app.r_des_w3 = data(13);
                            
                            app.r_d_x = data(14);
                            app.r_d_y = data(15);
                            app.r_d_z = data(16);
                            
                            app.r_uz = data(17);
                            app.r_g1 = data(18);
                            app.r_g2 = data(19);
                            app.r_g3 = data(20);
                            app.r_g4 = data(21);
                            
                            app.accStatus = data(22);
                            app.gyrStatus = data(23);
                            app.magStatus = data(24);
                            app.sysStatus = data(25);
                            
                            
                            app.r_lambda_phi =app.lambda_phi;
                            app.r_lambda_theta = app.lambda_theta;
                            app.r_lambda_psi = app.lambda_psi;
        
                            app.r_A_phi =app.A_phi;
                            app.r_A_theta =app.A_theta;
                            app.r_A_psi =app.A_psi;
        
                            app.r_B_phi =app.B_phi;
                            app.r_B_theta =app.B_theta;
                            app.r_B_psi =app.B_psi;
        
                            app.r_eps_phi =app.eps_phi;
                            app.r_eps_theta =app.eps_theta;
                            app.r_eps_psi =app.eps_psi;
                            
                            app.v_phi = app.r_phi;
                            app.v_theta = app.r_theta;
                            app.v_psi = -app.r_psi;
                            app.pwmInput = app.r_uz/47.93;
                            
                            app.showTelemetry();
                            app.Deflector1AngleGauge.Value = rad2deg(app.r_g1);
                            app.Deflector2AngleGauge.Value = rad2deg(app.r_g2);
                            app.Deflector3AngleGauge.Value = rad2deg(app.r_g3);
                            app.Deflector4AngleGauge.Value = rad2deg(app.r_g4);
                            
                            app.PhiTextArea.Value = num2str(app.r_phi);
                            app.ThetaTextArea.Value = num2str(app.r_theta);
                            app.PsiTextArea.Value = num2str(app.r_psi);
                            
                            app.r_e1TextArea.Value = num2str(round(app.v_pos_x,3));
                            app.r_e2TextArea.Value = num2str(round(app.v_pos_y,3));
                            app.r_e3TextArea.Value = num2str(round(app.v_pos_z,3));
                            
                            app.AccTextArea.Value = num2str(app.accStatus);
                            app.GyroTextArea.Value = num2str(app.gyrStatus);
                            app.MagTextArea.Value = num2str(app.magStatus);
                            app.SysTextArea.Value = num2str(app.sysStatus);
                            
                            app.DesPhiTextArea.Value = num2str(app.r_des_phi);
                            app.DesThetaTextArea.Value = num2str(app.r_des_theta);
                            app.ThrustTextArea.Value = num2str(app.pwmInput);


                            %{
                            app.calc_deflector_angles =calculateAttitudeControl(app,[app.r_phi;app.r_theta;app.r_psi],[app.r_des_phi;app.r_des_theta;app.r_des_psi],[app.r_w1;app.r_w2;app.r_w3],[app.r_des_w1;app.r_des_w2;app.r_des_w3],diag([app.r_lambda_phi,app.r_lambda_theta,app.r_lambda_psi]),diag([app.r_A_phi,app.r_A_theta,app.r_A_psi]),diag([app.r_B_phi,app.r_B_theta,app.r_B_psi]),[app.r_eps_phi;app.r_eps_theta;app.r_eps_psi]);
                            
                            app.ThrustTextArea.Value = sprintf('%2.3f',app.r_uz);
                            app.Deflector1TextArea.Value = sprintf('%1.4f',app.r_g1);
                            app.Deflector2TextArea.Value = sprintf('%1.4f',app.r_g2);
                            app.Deflector3TextArea.Value = sprintf('%1.4f',app.r_g3);
                            app.Deflector4TextArea.Value = sprintf('%1.4f',app.r_g4);
                            
                            app.Deflector1simTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(1));
                            app.Deflector2simTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(2));
                            app.Deflector3simTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(3));
                            app.Deflector4simTextArea.Value = sprintf('%1.4f',app.calc_deflector_angles(4));
                            
                            % Phi Data
                            app.LambdaPhiTextArea.Value = sprintf('%1.2f',app.r_lambda_phi);
                            app.APhiTextArea.Value = sprintf('%1.2f',app.r_A_phi);
                            app.BPhiTextArea.Value = sprintf('%1.2f',app.r_B_phi);
                            app.EpsPhiTextArea.Value = sprintf('%1.2f',app.r_eps_phi);
                            app.PhiErrorTextArea.Value = sprintf('%1.4f',app.calc_error_angles(1));
                            app.PhiRateErrorTextArea.Value = sprintf('%1.4f',app.calc_d_error_angles(1));
                            app.SPhiTextArea.Value = sprintf('%1.4f',app.calc_s_angles(1));
                            app.TorquePhiTextArea.Value = sprintf('%1.4f',app.calc_torque_angles(1));
                            
                            % Theta Data
                            app.LambdaThetaTextArea.Value = sprintf('%1.2f',app.r_lambda_theta);
                            app.AThetaTextArea.Value = sprintf('%1.2f',app.r_A_theta);
                            app.BThetaTextArea.Value = sprintf('%1.2f',app.r_B_theta);
                            app.EpsThetaTextArea.Value = sprintf('%1.2f',app.r_eps_theta);
                            app.ThetaErrorTextArea.Value = sprintf('%1.4f',app.calc_error_angles(2));
                            app.ThetaRateErrorTextArea.Value = sprintf('%1.4f',app.calc_d_error_angles(2));
                            app.SThetaTextArea.Value = sprintf('%1.4f',app.calc_s_angles(2));
                            app.TorqueThetaTextArea.Value = sprintf('%1.4f',app.calc_torque_angles(2));
                            
                            
                            % Psi Data
                            app.LambdaPsiTextArea.Value = sprintf('%1.2f',app.r_lambda_psi);
                            app.APsiTextArea.Value = sprintf('%1.2f',app.r_A_psi);
                            app.BPsiTextArea.Value = sprintf('%1.2f',app.r_B_psi);
                            app.EpsPsiTextArea.Value = sprintf('%1.2f',app.r_eps_psi);
                            app.PsiErrorTextArea.Value = sprintf('%1.4f',app.calc_error_angles(3));
                            app.PsiRateErrorTextArea.Value = sprintf('%1.4f',app.calc_d_error_angles(3));
                            app.SPsiTextArea.Value = sprintf('%1.4f',app.calc_s_angles(3));
                            app.TorquePsiTextArea.Value = sprintf('%1.4f',app.calc_torque_angles(3));
                            
                            app.PhiTextArea.Value = sprintf('%1.4f',app.r_phi);
                            app.ThetaTextArea.Value = sprintf('%1.4f',app.r_theta);
                            app.PsiTextArea.Value = sprintf('%1.4f',app.r_psi);
                            %}
                            
                    
                        end
                    end
                  
                    timeEnd = clock;
                    timeElapsed = round(etime(timeEnd,timeStart),4);
                    
                    if(timeElapsed < app.loopTime)
                        pause(app.loopTime - timeElapsed);
                    end
                    t = round(etime(clock,timeStart),4);
                    value = app.RunButton.Value;
                    
                    
                end
                
                
                clear u;
                fclose(fileID);
                
                release(app.udps);
                pause(0.01);
                
                app.DataStreamTextArea.Value = ""; 
                sData = sprintf('0,0');
                app.udps = dsp.UDPSender('RemoteIPAddress',app.arduinoIP,'RemoteIPPort',app.arduinoPort);
                pause(0.01);
                app.udps(uint8(sData));
                pause(0.01);
                release(app.udps);
                
            end
        end

        % Button pushed function: UpdateControllerGainsButton
        function UpdateControllerGainsButtonPushed(app, event)
        value = app.RunButton.Value;
        if(value)
            app.udps = dsp.UDPSender('RemoteIPAddress',app.arduinoIP,'RemoteIPPort',app.arduinoPort);
            
            if(app.ViconCheckBox.Value == 1)
                app.viconReceiveData(app.viconObject);
            end
            checkControlType(app);
                    
            app.setAlt = app.SetAltutudemEditField.Value;
            app.x_des = app.SetXPosmEditField.Value;
            app.y_des = app.SetYPosmEditField.Value;
                    
            app.pw_des = app.PWSlider.Value;
                    
            getControllerVariables(app);
             
            sData = sprintf('%1d,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%1.3f,%3.3f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.4f,%1.4f,%1.4f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.2f,%1.4f,%1.4f,%1.4f',app.controlType,app.v_pos_x,app.v_pos_y,app.v_pos_z,app.v_phi,app.v_theta,app.v_psi,app.x_des,app.y_des,app.setAlt,app.pw_des,app.lambda_phi,app.lambda_theta,app.lambda_psi,app.A_phi,app.A_theta,app.A_psi,app.B_phi,app.B_theta,app.B_psi,app.eps_phi,app.eps_theta,app.eps_psi,app.lambda_x,app.lambda_y,app.lambda_z,app.A_x,app.A_y,app.A_z,app.B_x,app.B_y,app.B_z,app.eps_x,app.eps_y,app.eps_z);
            app.sentData = sData;
            %disp(strlength(sData))
            %disp(sData);
            app.udps(uint8(sData));
            release(app.udps);
        end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            value = app.UIAxes.CurrentPoint;
            app.SetXPosmEditField.Value = value(1,2);
            app.SetYPosmEditField.Value = value(1,1);
            disp(value(1,1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Ground Station Beta v0.4';
            app.UDPConnectionSettingsPanel = uipanel(app.UIFigure);
            app.UDPConnectionSettingsPanel.Title = 'UDP Connection Settings';
            app.ArduinoIPAddressEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ArduinoIPAddressEditFieldLabel.Text = 'Arduino IP Address';
            app.ArduinoIPAddressEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.ArduinoIPAddressEditField.Value = '192.168.1.36';
            app.ViconIPAddressEditField_2Label = uilabel(app.UDPConnectionSettingsPanel);
            app.ViconIPAddressEditField_2Label.Text = 'Vicon IP Address';
            app.ViconIPAddressEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.ViconIPAddressEditField.Value = '192.168.1.33';
            app.StationIPAddressEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.StationIPAddressEditFieldLabel.Text = 'Station IP Address';
            app.StationIPAddressEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.StationIPAddressEditField.Value = '192.168.1.34';
            app.ArduinoPortEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ArduinoPortEditFieldLabel.Text = 'Arduino Port';
            app.ArduinoPortEditField = uieditfield(app.UDPConnectionSettingsPanel, 'numeric');
            app.ArduinoPortEditField.Value = 2390;
            app.ViconPortLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ViconPortLabel.Text = 'Vicon Port';
            app.ViconPortEditField = uieditfield(app.UDPConnectionSettingsPanel, 'numeric');
            app.ViconPortEditField.Value = 1;
            app.StationPortEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.StationPortEditFieldLabel.Text = 'Station Port';
            app.StationPortEditField = uieditfield(app.UDPConnectionSettingsPanel, 'numeric');
            app.StationPortEditField.Value = 2380;
            app.ConnectButton = uibutton(app.UDPConnectionSettingsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ViconObjectEditFieldLabel = uilabel(app.UDPConnectionSettingsPanel);
            app.ViconObjectEditFieldLabel.Text = 'Vicon Object';
            app.ViconObjectEditField = uieditfield(app.UDPConnectionSettingsPanel, 'text');
            app.ViconObjectEditField.Value = 'vtol22';
            app.ArduinoCheckBox = uicheckbox(app.UDPConnectionSettingsPanel);
            app.ArduinoCheckBox.Text = 'Arduino';
            app.ArduinoCheckBox.Value = true;
            app.ViconCheckBox = uicheckbox(app.UDPConnectionSettingsPanel);
            app.ViconCheckBox.Text = 'Vicon';
            app.ViconCheckBox.Value = true;
            app.DisconnectButton = uibutton(app.UDPConnectionSettingsPanel, 'state');
            app.DisconnectButton.ValueChangedFcn = createCallbackFcn(app, @DisconnectButtonValueChanged, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TelemetryPanel = uipanel(app.UIFigure);
            app.TelemetryPanel.Title = 'Telemetry';
            app.ArtificialHorizondegLabel = uilabel(app.TelemetryPanel);
            app.ArtificialHorizondegLabel.Text = 'ArtificialHorizon [deg]';
            app.ArtificialHorizondeg = uiaerohorizon(app.TelemetryPanel);
            app.AltimetercmLabel = uilabel(app.TelemetryPanel);
            app.AltimetercmLabel.Text = 'Altimeter [cm]';
            app.Altimetercm = uiaeroaltimeter(app.TelemetryPanel);
            app.HeadingdegHeadingIndicatorLabel = uilabel(app.TelemetryPanel);
            app.HeadingdegHeadingIndicatorLabel.Text = 'Heading [deg]';
            app.HeadingdegHeadingIndicator = uiaeroheading(app.TelemetryPanel);
            app.ThrustInputpwmRPMIndicatorLabel = uilabel(app.TelemetryPanel);
            app.ThrustInputpwmRPMIndicatorLabel.Text = 'Thrust Input [pwm]';
            app.ThrustInputpwmRPMIndicator = uiaerorpm(app.TelemetryPanel);
            app.DataStreamTextAreaLabel = uilabel(app.UIFigure);
            app.DataStreamTextAreaLabel.Text = 'Data Stream';
            app.DataStreamTextArea = uitextarea(app.UIFigure);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.RunButton = uibutton(app.ControlPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.SetAltutudemEditFieldLabel = uilabel(app.ControlPanel);
            app.SetAltutudemEditFieldLabel.Text = 'Set Altutude [m]';
            app.SetAltutudemEditField = uieditfield(app.ControlPanel, 'numeric');
            app.SetAltutudemEditField.Value = 0.7;
            app.PWSliderLabel = uilabel(app.ControlPanel);
            app.PWSliderLabel.Text = 'PW';
            app.PWSlider = uislider(app.ControlPanel);
            app.PWSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.PWSlider.Value = 1;
            app.ControlTypeButtonGroup = uibuttongroup(app.ControlPanel);
            app.ControlTypeButtonGroup.Title = 'Control Type';
            app.TelemetryButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.TelemetryButton.Text = 'Telemetry';
            app.TelemetryButton.Value = true;
            app.PositionButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.PositionButton.Text = 'Position';
            app.AttAltEDFOFFButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.AttAltEDFOFFButton.Text = 'Att, Alt, EDF OFF';
            app.AttitudeandAltitudeButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.AttitudeandAltitudeButton.Text = 'Attitude and Altitude';
            app.OnlyEDFButton = uiradiobutton(app.ControlTypeButtonGroup);
            app.OnlyEDFButton.Text = 'Only EDF';
            app.SetXPosmEditFieldLabel = uilabel(app.ControlPanel);
            app.SetXPosmEditFieldLabel.Text = 'Set X Pos [m]';
            app.SetXPosmEditField = uieditfield(app.ControlPanel, 'numeric');
            app.SetYPosmEditFieldLabel = uilabel(app.ControlPanel);
            app.SetYPosmEditFieldLabel.Text = 'Set Y Pos [m]';
            app.SetYPosmEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ControllerOutputFromVehiclePanel = uipanel(app.UIFigure);
            app.ControllerOutputFromVehiclePanel.Title = 'Controller Output From Vehicle';
            app.DeflectorAnglesPanel = uipanel(app.ControllerOutputFromVehiclePanel);
            app.DeflectorAnglesPanel.Title = 'Deflector Angles';
            app.Deflector1TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector1TextAreaLabel.Text = 'Deflector 1';
            app.Deflector1TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.Deflector3TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector3TextAreaLabel.Text = 'Deflector 3';
            app.Deflector3TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.Deflector4TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector4TextAreaLabel.Text = 'Deflector 4';
            app.Deflector4TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.Deflector2TextAreaLabel = uilabel(app.DeflectorAnglesPanel);
            app.Deflector2TextAreaLabel.Text = 'Deflector 2';
            app.Deflector2TextArea = uitextarea(app.DeflectorAnglesPanel);
            app.ThrustTextArea_2Label = uilabel(app.ControllerOutputFromVehiclePanel);
            app.ThrustTextArea_2Label.Text = 'Thrust';
            app.ThrustTextArea = uitextarea(app.ControllerOutputFromVehiclePanel);
            app.DesPhiTextAreaLabel = uilabel(app.ControllerOutputFromVehiclePanel);
            app.DesPhiTextAreaLabel.Text = 'Des Phi';
            app.DesPhiTextArea = uitextarea(app.ControllerOutputFromVehiclePanel);
            app.DesThetaTextAreaLabel = uilabel(app.ControllerOutputFromVehiclePanel);
            app.DesThetaTextAreaLabel.Text = 'Des Theta';
            app.DesThetaTextArea = uitextarea(app.ControllerOutputFromVehiclePanel);
            app.ControllerVariablesPanel = uipanel(app.UIFigure);
            app.ControllerVariablesPanel.Title = 'Controller Variables';
            app.HorizontalPositionPanel = uipanel(app.ControllerVariablesPanel);
            app.HorizontalPositionPanel.Title = 'Horizontal Position';
            app.LambdaXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.LambdaXEditFieldLabel.Text = 'Lambda X';
            app.LambdaXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.LambdaXEditField.Value = 0.1;
            app.AXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.AXEditFieldLabel.Text = 'A X';
            app.AXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.BXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.BXEditFieldLabel.Text = 'B X';
            app.BXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.BXEditField.Value = 0.1;
            app.epsXEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.epsXEditFieldLabel.Text = 'eps X';
            app.epsXEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.epsXEditField.Value = 0.01;
            app.LambdaYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.LambdaYEditFieldLabel.Text = 'Lambda Y';
            app.LambdaYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.LambdaYEditField.Value = 0.1;
            app.AYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.AYEditFieldLabel.Text = 'A Y';
            app.AYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.BYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.BYEditFieldLabel.Text = 'B Y';
            app.BYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.BYEditField.Value = 0.1;
            app.epsYEditFieldLabel = uilabel(app.HorizontalPositionPanel);
            app.epsYEditFieldLabel.Text = 'eps Y';
            app.epsYEditField = uieditfield(app.HorizontalPositionPanel, 'numeric');
            app.epsYEditField.Value = 0.01;
            app.AltitudePanel = uipanel(app.ControllerVariablesPanel);
            app.AltitudePanel.Title = 'Altitude';
            app.LambdaZEditFieldLabel = uilabel(app.AltitudePanel);
            app.LambdaZEditFieldLabel.Text = 'Lambda Z';
            app.LambdaZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.LambdaZEditField.Value = 0.01;
            app.AZEditFieldLabel = uilabel(app.AltitudePanel);
            app.AZEditFieldLabel.Text = 'A Z';
            app.AZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.AZEditField.Value = 0.01;
            app.BZEditFieldLabel = uilabel(app.AltitudePanel);
            app.BZEditFieldLabel.Text = 'B Z';
            app.BZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.BZEditField.Value = 0.01;
            app.epsZEditFieldLabel = uilabel(app.AltitudePanel);
            app.epsZEditFieldLabel.Text = 'eps Z';
            app.epsZEditField = uieditfield(app.AltitudePanel, 'numeric');
            app.epsZEditField.Value = 0.001;
            app.AttitudePanel = uipanel(app.ControllerVariablesPanel);
            app.AttitudePanel.Title = 'Attitude';
            app.LambdaPhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.LambdaPhiEditFieldLabel.Text = 'Lambda Phi';
            app.LambdaPhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.LambdaPhiEditField.Value = 5;
            app.APhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.APhiEditFieldLabel.Text = 'A Phi';
            app.APhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.APhiEditField.Value = 0.1;
            app.BPhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.BPhiEditFieldLabel.Text = 'B Phi';
            app.BPhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BPhiEditField.Value = 2;
            app.epsPhiEditFieldLabel = uilabel(app.AttitudePanel);
            app.epsPhiEditFieldLabel.Text = 'eps Phi';
            app.epsPhiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.epsPhiEditField.Value = 0.001;
            app.LambdaThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.LambdaThetaEditFieldLabel.Text = 'Lambda Theta';
            app.LambdaThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.LambdaThetaEditField.Value = 5;
            app.AThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.AThetaEditFieldLabel.Text = 'A Theta';
            app.AThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.AThetaEditField.Value = 0.1;
            app.BThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.BThetaEditFieldLabel.Text = 'B Theta';
            app.BThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BThetaEditField.Value = 2;
            app.epsThetaEditFieldLabel = uilabel(app.AttitudePanel);
            app.epsThetaEditFieldLabel.Text = 'eps Theta';
            app.epsThetaEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.epsThetaEditField.Value = 0.001;
            app.LambdaPsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.LambdaPsiEditFieldLabel.Text = 'Lambda Psi';
            app.LambdaPsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.LambdaPsiEditField.Value = 2;
            app.APsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.APsiEditFieldLabel.Text = 'A Psi';
            app.APsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.APsiEditField.Value = 0.1;
            app.BPsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.BPsiEditFieldLabel.Text = 'B Psi';
            app.BPsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.BPsiEditField.Value = 5;
            app.epsPsiEditFieldLabel = uilabel(app.AttitudePanel);
            app.epsPsiEditFieldLabel.Text = 'eps Psi';
            app.epsPsiEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.epsPsiEditField.Value = 0.01;
            app.DeflectorAnglesPanel_3 = uipanel(app.UIFigure);
            app.DeflectorAnglesPanel_3.Title = 'Deflector Angles';
            app.Deflector1AngleGaugeLabel = uilabel(app.DeflectorAnglesPanel_3);
            app.Deflector1AngleGaugeLabel.Text = 'Deflector 1 Angle';
            app.Deflector1AngleGauge = uigauge(app.DeflectorAnglesPanel_3, 'semicircular');
            app.Deflector1AngleGauge.Limits = [-13.5 13.5];
            app.Deflector1AngleGauge.MajorTicks = [-13.5 -10 -5 0 5 10 13.5];
            app.Deflector1AngleGauge.ScaleDirection = 'counterclockwise';
            app.Deflector2AngleGaugeLabel = uilabel(app.DeflectorAnglesPanel_3);
            app.Deflector2AngleGaugeLabel.Text = 'Deflector 2 Angle';
            app.Deflector2AngleGauge = uigauge(app.DeflectorAnglesPanel_3, 'semicircular');
            app.Deflector2AngleGauge.Limits = [-13.5 13.5];
            app.Deflector2AngleGauge.MajorTicks = [-13.5 -10 -5 0 5 10 13.5];
            app.Deflector2AngleGauge.ScaleDirection = 'counterclockwise';
            app.Deflector3AngleGaugeLabel = uilabel(app.DeflectorAnglesPanel_3);
            app.Deflector3AngleGaugeLabel.Text = 'Deflector 3 Angle';
            app.Deflector3AngleGauge = uigauge(app.DeflectorAnglesPanel_3, 'semicircular');
            app.Deflector3AngleGauge.Limits = [-13.5 13.5];
            app.Deflector3AngleGauge.MajorTicks = [-13.5 -10 -5 0 5 10 13.5];
            app.Deflector3AngleGauge.ScaleDirection = 'counterclockwise';
            app.Deflector4AngleGaugeLabel = uilabel(app.DeflectorAnglesPanel_3);
            app.Deflector4AngleGaugeLabel.Text = 'Deflector 4 Angle';
            app.Deflector4AngleGauge = uigauge(app.DeflectorAnglesPanel_3, 'semicircular');
            app.Deflector4AngleGauge.Limits = [-13.5 13.5];
            app.Deflector4AngleGauge.MajorTicks = [-13.5 -10 -5 0 5 10 13.5];
            app.Deflector4AngleGauge.ScaleDirection = 'counterclockwise';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.AttitudeandPositionPanel = uipanel(app.DataPanel);
            app.AttitudeandPositionPanel.Title = 'Attitude and Position';
            app.PhiTextArea_2Label = uilabel(app.AttitudeandPositionPanel);
            app.PhiTextArea_2Label.Text = 'Phi';
            app.PhiTextArea = uitextarea(app.AttitudeandPositionPanel);
            app.ThetaTextAreaLabel = uilabel(app.AttitudeandPositionPanel);
            app.ThetaTextAreaLabel.Text = 'Theta';
            app.ThetaTextArea = uitextarea(app.AttitudeandPositionPanel);
            app.PsiTextAreaLabel = uilabel(app.AttitudeandPositionPanel);
            app.PsiTextAreaLabel.Text = 'Psi';
            app.PsiTextArea = uitextarea(app.AttitudeandPositionPanel);
            app.r_e1TextAreaLabel = uilabel(app.AttitudeandPositionPanel);
            app.r_e1TextAreaLabel.Text = 'r_e1';
            app.r_e1TextArea = uitextarea(app.AttitudeandPositionPanel);
            app.r_e2TextAreaLabel = uilabel(app.AttitudeandPositionPanel);
            app.r_e2TextAreaLabel.Text = 'r_e2';
            app.r_e2TextArea = uitextarea(app.AttitudeandPositionPanel);
            app.r_e3TextAreaLabel = uilabel(app.AttitudeandPositionPanel);
            app.r_e3TextAreaLabel.Text = 'r_e3';
            app.r_e3TextArea = uitextarea(app.AttitudeandPositionPanel);
            app.IMUStatusPanel = uipanel(app.DataPanel);
            app.IMUStatusPanel.Title = 'IMU Status';
            app.AccTextAreaLabel = uilabel(app.IMUStatusPanel);
            app.AccTextAreaLabel.Text = 'Acc';
            app.AccTextArea = uitextarea(app.IMUStatusPanel);
            app.GyroTextAreaLabel = uilabel(app.IMUStatusPanel);
            app.GyroTextAreaLabel.Text = 'Gyro';
            app.GyroTextArea = uitextarea(app.IMUStatusPanel);
            app.MagTextAreaLabel = uilabel(app.IMUStatusPanel);
            app.MagTextAreaLabel.Text = 'Mag';
            app.MagTextArea = uitextarea(app.IMUStatusPanel);
            app.SysTextAreaLabel = uilabel(app.IMUStatusPanel);
            app.SysTextAreaLabel.Text = 'Sys';
            app.SysTextArea = uitextarea(app.IMUStatusPanel);
            app.UpdateControllerGainsButton = uibutton(app.UIFigure, 'push');
            app.UpdateControllerGainsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateControllerGainsButtonPushed, true);
            app.UpdateControllerGainsButton.Text = 'Update Controller Gains';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position')
            xlabel(app.UIAxes, 'e2')
            ylabel(app.UIAxes, 'e1')
            zlabel(app.UIAxes, 'e3')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.ZLim = [-5 5];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ground_station_flight_software_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end